classdef SURsuite_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                     matlab.ui.Figure
        FindAPThresholdEditField    matlab.ui.control.NumericEditField
        FindAPThresholdLabel        matlab.ui.control.Label
        AlignSyncSigs               matlab.ui.control.Button
        EndofAPButton               matlab.ui.control.StateButton
        MaxHyperpolarizationButton  matlab.ui.control.StateButton
        MaxDepolarization2Button    matlab.ui.control.StateButton
        MaxDepolarization1Button    matlab.ui.control.StateButton
        StartofAPButton             matlab.ui.control.StateButton
        AnalyzeMotion               matlab.ui.control.Button
        FindAPs                     matlab.ui.control.Button
        SAVEDATAButton              matlab.ui.control.Button
        APShapeDetButton            matlab.ui.control.Button
        PlotElectrodeCombinations   matlab.ui.control.Button
        SaveDirectory               matlab.ui.control.EditField
        text2_2                     matlab.ui.control.Label
        TabGroup                    matlab.ui.container.TabGroup
        AnalysisPlotsTab            matlab.ui.container.Tab
        PlotRawDataSwitch           matlab.ui.control.Switch
        PlotRawDataSwitchLabel      matlab.ui.control.Label
        ArtAnalysisInstructions     matlab.ui.control.Label
        APInstructions              matlab.ui.control.Label
        stopButton                  matlab.ui.control.StateButton
        ArtRemoveShorts             matlab.ui.control.Label
        ReSizeTempButton            matlab.ui.control.StateButton
        BinTempButton               matlab.ui.control.StateButton
        SplineTempButton            matlab.ui.control.StateButton
        MotionSyncShorts            matlab.ui.control.Label
        continueButton              matlab.ui.control.Button
        KeepCEDSYNCButton           matlab.ui.control.StateButton
        KeepFSYNCoButton            matlab.ui.control.StateButton
        MotionStimPlotsTab          matlab.ui.container.Tab
        SaveMotionStimFig           matlab.ui.control.Button
        XYZAcc                      matlab.ui.control.CheckBox
        LRZVel                      matlab.ui.control.CheckBox
        XYZVel                      matlab.ui.control.CheckBox
        MotionNerveCon              matlab.ui.control.UIAxes
        MotionLow                   matlab.ui.control.UIAxes
        MotionMid                   matlab.ui.control.UIAxes
        MotionHigh                  matlab.ui.control.UIAxes
        SpikeProbabilityPlot        matlab.ui.container.Tab
        SaveSPPlot                  matlab.ui.control.Button
        PlotMotion                  matlab.ui.control.Button
        text8                       matlab.ui.control.Label
        MotionStimList              matlab.ui.control.ListBox
        SubtractArtifact            matlab.ui.control.Button
        text7                       matlab.ui.control.Label
        CurrentList                 matlab.ui.control.ListBox
        text6                       matlab.ui.control.Label
        RefList                     matlab.ui.control.ListBox
        text5                       matlab.ui.control.Label
        StimList                    matlab.ui.control.ListBox
        InitialChannelTemps         matlab.ui.control.Button
        text4                       matlab.ui.control.Label
        ChannelList                 matlab.ui.control.ListBox
        FilePathText                matlab.ui.control.EditField
        text2                       matlab.ui.control.Label
        LoadFile                    matlab.ui.control.Button
        FigPlot                     matlab.ui.control.UIAxes
        ProgressBar                 matlab.ui.control.UIAxes
    end


    properties (Access = public)
        PBarObj % Description
        PBarTxt % Description
        filedata % Description
        DAT
        motionTime
        larp
        ralp
        x
        y
        z
        xA
        yA
        zA
        IsR
        IsRt
        DebugFlgAll
        DebugFlgSingle
        APorientation = 0;
        APLocPlot
        CurrID
        CurrChannel
        APInfoPlots = struct();
        mouseIsDown = false;
        movingPlotHndle
        prevPoint
        KeyPressed = 0;
        TB
        debugPlots
        idxs
        ALLSTIM
        ZAPPED
        ALLSTIM2
        ZAPPED2
        ALLSTIM3
        ZAPPED3
        temp2Use
        temp2Use2
        noresponse
        TemplateStyle
        Positivepks
        tempAPLoc
        tempAPCount

        APThreshChange = 0;

        TemplateRedo = 0;
    end

    properties (Access = private)
    end

    methods (Access = public)

        function ChangeMotionStimPlots(app)
            if ~isempty(app.x)
                app.MotionHigh.XLimMode = 'auto';
                app.MotionHigh.YLimMode = 'auto';
                app.MotionMid.XLimMode = 'auto';
                app.MotionMid.YLimMode = 'auto';
                app.MotionLow.XLimMode = 'auto';
                app.MotionLow.YLimMode = 'auto';
                if app.LRZVel.Value
                    plot(app.MotionHigh,app.motionTime,app.larp)
                    app.MotionHigh.Title.String = 'LARP Velocity';
                    app.MotionHigh.YLabel.String = 'm/s';
                    plot(app.MotionMid,app.motionTime,app.ralp)
                    app.MotionMid.Title.String = 'RALP Velocity';
                    app.MotionMid.YLabel.String = 'm/s';
                    plot(app.MotionLow,app.motionTime,app.z)
                    app.MotionLow.Title.String = 'Z Velocity';
                    app.MotionLow.YLabel.String = 'm/s';
                    sameaxes([],[app.MotionHigh,app.MotionMid,app.MotionLow])
                    plot(app.MotionNerveCon,app.IsRt,app.IsR,'k.')
                    app.MotionNerveCon.YLim = [mean(app.IsR)-50 mean(app.IsR)+50];
                    app.XYZVel.Value = 0;
                    app.XYZAcc.Value = 0;
                elseif app.XYZAcc.Value
                    plot(app.MotionHigh,app.motionTime,app.xA)
                    app.MotionHigh.Title.String = 'X Acceleration';
                    app.MotionHigh.YLabel.String = 'm/s^2';
                    plot(app.MotionMid,app.motionTime,app.yA)
                    app.MotionMid.Title.String = 'Y Acceleration';
                    app.MotionMid.YLabel.String = 'm/s^2';
                    plot(app.MotionLow,app.motionTime,app.zA)
                    app.MotionLow.Title.String = 'Z Acceleration';
                    app.MotionLow.YLabel.String = 'm/s^2';
                    sameaxes([],[app.MotionHigh,app.MotionMid,app.MotionLow])
                    plot(app.MotionNerveCon,app.IsRt,app.IsR,'k.')
                    app.MotionNerveCon.YLim = [mean(app.IsR)-50 mean(app.IsR)+50];
                    app.XYZVel.Value = 0;
                    app.LRZVel.Value = 0;
                elseif app.XYZVel.Value
                    plot(app.MotionHigh,app.motionTime,app.x)
                    app.MotionHigh.Title.String = 'X Velocity';
                    app.MotionHigh.YLabel.String = 'm/s';
                    plot(app.MotionMid,app.motionTime,app.y)
                    app.MotionMid.Title.String = 'Y Velocity';
                    app.MotionMid.YLabel.String = 'm/s';
                    plot(app.MotionLow,app.motionTime,app.z)
                    app.MotionLow.Title.String = 'Z Velocity';
                    app.MotionLow.YLabel.String = 'm/s';
                    sameaxes([],[app.MotionHigh,app.MotionMid,app.MotionLow])
                    plot(app.MotionNerveCon,app.IsRt,app.IsR,'k.')
                    app.MotionNerveCon.YLim = [mean(app.IsR)-50 mean(app.IsR)+50];
                    app.XYZVel.Value = 0;
                    app.XYZAcc.Value = 0;
                end
            end
        end

        function ChangeProgressBar(app, title, CurrPos, TotalPos, reset)
            if reset
                app.ProgressBar.Title.String = ['Finished'];
                app.PBarObj.Position(3) = 0;
                app.PBarTxt.String = 'Waiting';
                drawnow
            else
                app.ProgressBar.Title.String = title;
                app.PBarObj.Position(3) = CurrPos/TotalPos*1000;
                app.PBarTxt.String = [num2str(round(CurrPos/TotalPos*100)),'%'];
                drawnow
            end
        end

        function APPosCheck(app,event)
            axL = app.FigPlot.InnerPosition(1);
            axB = app.FigPlot.InnerPosition(2);
            axW = app.FigPlot.InnerPosition(3);
            axH = app.FigPlot.InnerPosition(4);
            cpx = app.figure1.CurrentPoint(1);
            cpy = app.figure1.CurrentPoint(2);
            if cpx>axL && cpy>axB && cpx < (axL+axW) && cpy<(axB+axH)
                ptX = app.APLocPlot.CurrentPoint(1);
                ptY = app.APLocPlot.CurrentPoint(3);
                idxs = app.CurrID;
                chan = app.CurrChannel;
                D = app.DAT;
                APLoc = app.tempAPLoc;
                APCount = app.tempAPCount;
%                 APLoc = D.StimBlocks(idxs).([chan,'_APLoc']);
%                 APCount = D.StimBlocks(idxs).([chan,'_APCount']);
                sz = size(APLoc);
                sp = fix(ptX/sz(2))+1;
                stPt = round(ptX-((sp-1)*sz(2)));
                if stPt - 100<0
                    bd = 1:stPt+100;
                    adj = 0;
                elseif stPt + 100 > sz(2)
                    bd = stPt-100:sz(2);
                    adj = stPt-100-1;
                else
                    bd = stPt-100:stPt+100;
                    adj = stPt-100-1;
                end
                if any(APLoc(sp,bd))
                    mIDADJ = find(APLoc(sp,bd))+adj;
                    APLoc(sp,mIDADJ) = 0;
                    APCount = APCount - length(mIDADJ);

                    app.tempAPLoc = APLoc;
                    app.tempAPCount = APCount;
%                     D.StimBlocks(idxs).([chan,'_APLoc']) = APLoc;
%                     D.StimBlocks(idxs).([chan,'_APCount']) = APCount;
                else
                    dat = D.StimBlocks(idxs).([chan,'_ZAPPED'])(sp,:);
                    [mval,mID]=max(dat(bd));
                    if mval > ptY*.5
                        mIDADJ = mID+adj;
                        APLoc(sp,mIDADJ) = 1;
                        APCount = APCount + 1;

                        app.tempAPLoc = APLoc;
                        app.tempAPCount = APCount;
                        %                     D.StimBlocks(idxs).([chan,'_APLoc']) = APLoc;
                        %                     D.StimBlocks(idxs).([chan,'_APCount']) = APCount;
                    end
                end
                apl = reshape(app.tempAPLoc',1,198000);
                ZO = app.FigPlot.Children(2).YData;
                app.FigPlot.Children(1).XData = find(apl);
                app.FigPlot.Children(1).YData = ZO(find(apl));
%                 cla(app.FigPlot);
%                 aa = app.FigPlot;
%                 ALLSTIMPlot = reshape(D.StimBlocks(idxs).([chan,'_ALLSTIM'])',1,198000);
%                 ZO = reshape(D.StimBlocks(idxs).([chan,'_ZAPPED'])',1,198000);
%                 apl = reshape(app.tempAPLoc',1,198000);
%                 plot(aa,ALLSTIMPlot)
%                 hold(aa,'on')
%                 plot(aa,ZO)
%                 plot(aa,find(apl),ZO(find(apl)),'g*')
%                 hold(aa,'off')
                app.FigPlot.Title.String = [num2str(APCount),' Total APs'];
%                 tb = axtoolbar(app.FigPlot,{'pan','zoomin','zoomout','restoreview'});
                drawnow
            end
        end

        function APInfoMouseDown(app,evet)
            currentPoint = app.figure1.CurrentPoint;
            x            = currentPoint(1,1);
            y            = currentPoint(1,2);
            % get the position of the axes within the GUI
            axesPos = app.FigPlot.Position;
            minx    = axesPos(1);
            miny    = axesPos(2);
            maxx    = minx + axesPos(3);
            maxy    = miny + axesPos(4);
            % is the mouse down event within the axes?
            if x>=minx && x<=maxx && y>=miny && y<=maxy
                    % get the position of the mouse down event within the axes
                    currentPoint = app.FigPlot.CurrentPoint;
                    x            = currentPoint(2,1);
                    y            = currentPoint(2,2);
                    % we are going to use the x and y data for each graphic object
                    % and determine which one is closest to the mouse down event
                    minDist      = Inf;
                    minHndl      = 0;
                    for k=2:length(app.APInfoPlots)
                        xData = get(app.APInfoPlots(k),'XData');
                        yData = get(app.APInfoPlots(k),'YData');
                        dist  = min((xData-x).^2+(yData-y).^2);
                        if dist<minDist
                            minHndl = app.APInfoPlots(k);
                            minDist = dist;
                        end
                    end
                    % if we have a graphics handle that is close to the mouse down
                    % event/position, then save the data
                    if minHndl~=0
                        app.mouseIsDown     = true;
                        app.movingPlotHndle = minHndl;
                        app.prevPoint       = [x y];
                    end
            end
        end

        function APInfoMouseUp(app,event)
                if app.mouseIsDown
                    % reset all moving graphic fields
                    app.mouseIsDown     = false;
                    app.movingPlotHndle = [];
                    app.prevPoint       = [];
                end
        end

        function APInfoMoseMove(app,event)
                if app.mouseIsDown
                    currentPoint = get(app.FigPlot, 'CurrentPoint');
                    x            = currentPoint(2,1);
                    y            = currentPoint(2,2);
                    xData = get(app.movingPlotHndle,'XData');
                    yData = get(app.movingPlotHndle,'YData');
                    % compute the displacement from previous position to current
                    t = 1:length(app.APInfoPlots(1).YData);
                    [a,b] = min(abs(t-x));
                    %xDiff = x - handles.prevPoint(1);
                    xDiff = t(b) - xData(1);
                    yDiff = 0;
                    % adjust this for the data corresponding to movingPlotHndle

                    set(app.movingPlotHndle,'YData',yData+yDiff,'XData',xData+xDiff);
                    app.prevPoint = [x y];
                end
        end

        function KeyDownFcn(app,event)
            key = double(app.figure1.CurrentCharacter);
            if ismember(app.TabGroup.SelectedTab.Title,{'Analysis Plots'}) && ismember(app.KeepCEDSYNCButton.Visible,{'on'})
                if app.KeyPressed < 1
                    switch key
                        case 99 %'c'
                            app.KeepCEDSYNCButton.Value = ~app.KeepCEDSYNCButton.Value;
                            uiresume(app.figure1)
                        case 102 %'f'
                            app.KeepFSYNCoButton.Value = ~app.KeepFSYNCoButton.Value;
                            uiresume(app.figure1)
                        case 98 %'b'
                            app.KeepCEDSYNCButton.Value = ~app.KeepCEDSYNCButton.Value;
                            app.KeepFSYNCoButton.Value = ~app.KeepFSYNCoButton.Value;
                            uiresume(app.figure1)
                        case 8 %'backspace'
                            app.continueButton.UserData = -1;
                            uiresume(app.figure1)
                        case 13 %'return'
                            uiresume(app.figure1)
                    end
                    app.KeyPressed = 1;
                else
                    app.KeyPressed = 0;
                end
            elseif ismember(app.TabGroup.SelectedTab.Title,{'Analysis Plots'}) && ismember(app.PlotRawDataSwitch.Visible,{'on'})
                    switch key
                        case 114 %'r'
                            switch app.PlotRawDataSwitch.Value
                                case 'On'
                                    app.PlotRawDataSwitch.Value = 'Off';
                                    app.APLocPlot.Children(3).Visible = 'off';
                                case 'Off'
                                    app.PlotRawDataSwitch.Value = 'On';
                                    app.APLocPlot.Children(3).Visible = 'on';
                            end
                        case 115 %'s'
                            app.stopButton.Value = 1;
                            app.ChangeProgressBar([],[],[],1)
                            uiresume(app.figure1)
                        case 122 %'z, scroll plot to the left'
                            app.APLocPlot.XLim = app.APLocPlot.XLim-1000;
                        case 120 %'x, scroll plot to the right'
                            app.APLocPlot.XLim = app.APLocPlot.XLim+1000;
                        case 13 %'return'
                            uiresume(app.figure1)
                    end

            end

            if ismember(app.TabGroup.SelectedTab.Title,{'Analysis Plots'}) && ismember(app.SplineTempButton.Visible,{'on'})
                if app.KeyPressed < 1
                    switch key
                        case 115 %'s'
                            app.SplineTempButton.Value = ~app.SplineTempButton.Value;
                            uiresume(app.figure1)
                        case 98 %'b'
                            app.BinTempButton.Value = ~app.BinTempButton.Value;
                            uiresume(app.figure1)
                        case 114 %'r'
                            app.ReSizeTempButton.Value = ~app.ReSizeTempButton.Value;
                            uiresume(app.figure1)
                    end
                    app.KeyPressed = 1;
                else
                    app.KeyPressed = 0;
                end
            end
        end

        function ArtifactSubMouseDown(app, event)
            currentPoint = app.figure1.CurrentPoint;
            x            = currentPoint(1,1);
            y            = currentPoint(1,2);
            tabminX = app.TabGroup.Position(1);
            tabminY = app.TabGroup.Position(2);
            tabmaxX = app.TabGroup.Position(3)+tabminX;
            tabmaxY = app.TabGroup.Position(4)+tabminY;
            if x>=tabminX && x<=tabmaxX && y>=tabminY && y<=tabmaxY
                fs = fields(app.debugPlots);
                newX = x-tabminX;
                newY = y-tabminY;
                if any(ismember(fs,'ff'))
                    app.debugPlots = rmfield(app.debugPlots,'ff');
                    fs = fields(app.debugPlots);
                end
                for ii = 1:length(fs)
                    plotminX = (tabmaxX-tabminX)*app.debugPlots.(fs{ii}).Position(1);
                    plotminY = (tabmaxY-tabminY)*app.debugPlots.(fs{ii}).Position(2);
                    plotmaxX = ((tabmaxX-tabminX)*app.debugPlots.(fs{ii}).Position(3))+plotminX;
                    plotmaxY = ((tabmaxY-tabminY)*app.debugPlots.(fs{ii}).Position(4))+plotminY;
                    if newX>=plotminX && newX<=plotmaxX && newY>=plotminY && newY<=plotmaxY
                        if ismember(app.figure1.SelectionType,{'normal'})
                            ff = figure;
                            ff.Position = [49   262   879   631];
                            copyobj(app.debugPlots.(fs{ii}),ff);
                            ff.CurrentAxes.Position = [0.075 0.075 0.875 0.875];
                            app.debugPlots.ff = ff;
                        elseif ismember(app.figure1.SelectionType,{'alt'})
                            if ismember(fs(ii),'artPoints')
                                ff = uifigure;
                                ff.Position = [49   262   879   631];
                                copyobj(app.debugPlots.(fs{ii}),ff);
                                ff.CurrentAxes.Position = [0.02 0.075 0.8 0.875];
                                app.debugPlots.ff = ff;
                                s = uislider(ff,'Orientation','vertical');
                                s.Position = [750 125 3 475];
                                s2 = uislider(ff,'Orientation','vertical');
                                s2.Position = [820 125 3 475];

                                if length(ff.Children(end).Children) == 10
                                    addPs = 0;
                                else
                                    addPs = 3;
                                end
                                V = ff.Children(end).Children(5+addPs).XData;
                                s.Limits = [V-100 V+100];
                                s.Value = V;
                                s.ValueChangedFcn = @(s,event)SliderChangeCallback(app,event);

                                V2 = ff.Children(end).Children(4+addPs).XData;
                                s2.Limits = [1 V2+200];
                                s2.Value = V2;
                                s2.ValueChangedFcn = @(s2,event)SliderChangeCallbackEndPt(app,event);

                                txa = uitextarea(ff);
                                txa.Position = [730 50 70 60];
                                txa.Value = num2str(s.Value);
                                txa.HorizontalAlignment = 'center';
                                txa.FontWeight = 'bold';
                                txa.FontSize = 25;
                                txa.ValueChangedFcn = @(txa,event)txtBxChangeCallback(app,event);
                                
                                txV = uitextarea(ff);
                                txV.Position = [730 610 70 20];
                                txV.Value = 'Start';
                                txV.HorizontalAlignment = 'center';
                                txV.FontWeight = 'bold';
                                txV.FontSize = 15;

                                txa2 = uitextarea(ff);
                                txa2.Position = [800 50 70 60];
                                txa2.Value = num2str(s2.Value);
                                txa2.HorizontalAlignment = 'center';
                                txa2.FontWeight = 'bold';
                                txa2.FontSize = 25;
                                txa2.ValueChangedFcn = @(txa2,event)txtBxChangeCallback2(app,event);

                                txV = uitextarea(ff);
                                txV.Position = [800 610 70 20];
                                txV.Value = 'End';
                                txV.HorizontalAlignment = 'center';
                                txV.FontWeight = 'bold';
                                txV.FontSize = 15;

                                btn = uibutton(ff);
                                btn.Position = [800 5 60 22];
                                btn.Text = 'Continue';
                                btn.ButtonPushedFcn = @(btn,event)continueBtnChangeCallback(app,event);
                                btn2 = uibutton(ff);
                                btn2.Position = [700 5 90 22];
                                btn2.Text = 'Binned Temp';
                                btn2.ButtonPushedFcn = @(btn2,event)binnedTempBtnChangeCallback(app,event);

                                app.debugPlots.s = s;
                                app.debugPlots.s2 = s2;
                                app.debugPlots.txa = txa;
                                app.debugPlots.txa2 = txa2;
                                app.debugPlots.btn = btn;
                                app.debugPlots.btn2 = btn2;
                                uiwait(app.debugPlots.ff)
                                app.debugPlots = rmfield(app.debugPlots,'s');
                                app.debugPlots = rmfield(app.debugPlots,'s2');
                                app.debugPlots = rmfield(app.debugPlots,'txa');
                                app.debugPlots = rmfield(app.debugPlots,'txa2');
                                app.debugPlots = rmfield(app.debugPlots,'btn');
                                app.debugPlots = rmfield(app.debugPlots,'btn2');
                                delete(app.debugPlots.ff)
                                app.ALLSTIM3 = reshape(app.debugPlots.third.Children(2).YData,1000,198)';
                                app.ZAPPED3 = reshape(app.debugPlots.third.Children(1).YData,1000,198)';
                                % important point: ff.Children.Children(5) -
                                % blue and ff.Children.Children(6) - green
                                % ff.Children.Children(4) - black
                                % important line: ff.Children.Children(1) <- spline
                                % important line: ff.Children.Children(3) <- split
                                % important line: ff.Children.Children(10) <- Orig
                            elseif ismember(fs(ii),'ReartPoints')
                                ff = uifigure;
                                ff.Position = [49   262   879   631];
                                copyobj(app.debugPlots.(fs{ii}),ff);
                                ff.CurrentAxes.Position = [0.075 0.075 0.825 0.875];
                                app.debugPlots.ff = ff;
                                s = uislider(ff,'Orientation','vertical');
                                s.Position = [820 125 3 475];

                                s.Value = ff.Children(end).Children(1).XData;
                                s.Limits = [s.Value-30 s.Value+50];
                                s.ValueChangedFcn = @(s,event)SliderChangeCallback2(app,event);
                                txa = uitextarea(ff);
                                txa.Position = [800 50 70 60];
                                txa.Value = num2str(s.Value);
                                txa.HorizontalAlignment = 'center';
                                txa.FontWeight = 'bold';
                                txa.FontSize = 25;
                                txa.ValueChangedFcn = @(txa,event)txtBxChangeCallback(app,event);
                                btn = uibutton(ff);
                                btn.Position = [800 5 60 22];
                                btn.Text = 'Continue';
                                btn.ButtonPushedFcn = @(btn,event)continueBtnChangeCallback(app,event);
%                                 btn2 = uibutton(ff);
%                                 btn2.Position = [700 5 90 22];
%                                 btn2.Text = 'Resized Temp';
%                                 btn2.ButtonPushedFcn = @(btn2,event)binnedTempBtnChangeCallback(app,event);
                                app.debugPlots.s = s;
                                app.debugPlots.txa = txa;
                                app.debugPlots.btn = btn;
%                                 app.debugPlots.btn2 = btn2;
                                uiwait(app.debugPlots.ff)
                                app.debugPlots = rmfield(app.debugPlots,'s');
                                app.debugPlots = rmfield(app.debugPlots,'txa');
                                app.debugPlots = rmfield(app.debugPlots,'btn');
%                                 app.debugPlots = rmfield(app.debugPlots,'btn2');
                                delete(app.debugPlots.ff)
                                app.ALLSTIM3 = reshape(app.debugPlots.third.Children(2).YData,1000,198)';
                                app.ZAPPED3 = reshape(app.debugPlots.third.Children(1).YData,1000,198)';
                                % important point: ff.Children.Children(5) -
                                % blue and ff.Children.Children(6) - green
                                % ff.Children.Children(4) - black
                                % important line: ff.Children.Children(1) <- spline
                                % important line: ff.Children.Children(3) <- split
                                % important line: ff.Children.Children(10) <- Orig
                            elseif ismember(fs(ii),'apPoints')
%                                 ff = uifigure;
%                                 ff.Position = [49   262   879   631];
%                                 fa = axes(ff);
%                                 fa.Position = [0.02 0.075 0.8 0.875];
%                                 app.debugPlots.ff = ff;
                                % 1. find APs like what is done during
                                TC = Template_class(app.DAT);
                                APC = ActionPotential_class(app.DAT);
                                APTEMPLATE_unzeroed = struct();
                                APTEMPLATE = struct();
                                chan = app.ChannelList.Value;
                                [DPRE,DSTIM,StimIdx] = app.DAT.GetBlockData(app.idxs, chan);
                                [APTEMPLATE_unzeroed,APTEMPLATE] = APC.FindAPs(DPRE);
                                app.DAT.StimBlocks(app.idxs).([chan,'_APTemplate_unzeroed']) = APTEMPLATE_unzeroed;
                                app.DAT.StimBlocks(app.idxs).([chan,'_APTemplate']) = APTEMPLATE;
                                uiresume(app.figure1)
                                focus(app.figure1)
                                app.TemplateRedo = 1;
%                                 [ALLAPTEMPLATE_unzeroedt, ALLAPTEMPLATEt] = obj.GetAPTemplate(APC, i, chan);
                                % initial AP template search
                                % 2. Plot the data used for finding AP
                                % template
                                % 3. Plot what is considered an AP
                                % 4. Choose which are APs and which aren't
                                % 5. Show remade AP template
                                % 6. Confirm new AP template
                                % 7. replace original AP template data
                                % 8. Restart zapping process for that index
                                % --- allow for cancel
                            end
                        end
                    end
                end

            end

        end

        function ArtifactSubMouseUp(app, event)
            if ismember(app.figure1.SelectionType,{'normal'})
                if isfield(app.debugPlots,'ff')
                    delete(app.debugPlots.ff)
                end
            else
            end
        end

        function SliderChangeCallback(app, event)
            app.debugPlots.s.Value = round(app.debugPlots.s.Value);
            app.debugPlots.txa.Value = num2str(app.debugPlots.s.Value);
            new = app.debugPlots.s.Value;
            if length(app.debugPlots.ff.Children(end).Children) == 10
                addPs = 0;
            else
                addPs = 3;
            end
            o1 = app.debugPlots.ff.Children(end).Children(5+addPs).XData;
            o2 = app.debugPlots.ff.Children(end).Children(6+addPs).XData;
            do = o2-o1;
            oX = app.debugPlots.ff.Children(end).Children(end).XData;
            oY = app.debugPlots.ff.Children(end).Children(end).YData;
            eSplit = app.debugPlots.ff.Children(end).Children(4+addPs).XData;
            nSx = [oX(1:new) oX(eSplit:end)];
            nSy = [oY(1:new) oY(eSplit:end)];
            app.debugPlots.ff.Children(end).Children(3+addPs).XData = nSx;
            app.debugPlots.ff.Children(end).Children(3+addPs).YData = nSy;

            app.debugPlots.ff.Children(end).Children(5+addPs).XData = new;
            app.debugPlots.ff.Children(end).Children(5+addPs).YData = oY(new);

            app.debugPlots.ff.Children(end).Children(6+addPs).XData = new+do;
            app.debugPlots.ff.Children(end).Children(6+addPs).YData = oY(new+do);

            [curve, goodness, output] = fit(nSx',nSy','smoothingspline'); %Fitting a spline to the smoothed and blanked artifact template
            xTemplate = oX;
            yTemplate = curve(oX)';

            yT = app.debugPlots.ff.Children(end).Children(end).YData;
            yT(new:eSplit) = yTemplate(new:eSplit);

            app.debugPlots.ff.Children(end).Children(1+addPs).XData = xTemplate;
            app.debugPlots.ff.Children(end).Children(1+addPs).YData = yT;
            app.temp2Use = yT;

            ZAP = ZapQuick_class(app.DAT);
            [app.ALLSTIM, app.ZAPPED] = ZAP.ZapBlock(app.idxs,yT,app.ChannelList.Value, [],[]);
            Z= reshape(app.ZAPPED',1,198000);
            AS = reshape(app.ALLSTIM',1,198000);
            app.debugPlots.first.Children(1).YData = Z;

        end

        function SliderChangeCallbackEndPt(app, event)
            app.debugPlots.s2.Value = round(app.debugPlots.s2.Value);
            app.debugPlots.txa2.Value = num2str(app.debugPlots.s2.Value);
            
            if length(app.debugPlots.ff.Children(end).Children) == 10
                addPs = 0;
            else
                addPs = 3;
            end
            new = app.debugPlots.ff.Children(end).Children(5+addPs).XData;
            oX = app.debugPlots.ff.Children(end).Children(end).XData;
            oY = app.debugPlots.ff.Children(end).Children(end).YData;
            eSplit = app.debugPlots.s2.Value;
            nSx = [oX(1:new) oX(eSplit:end)];
            nSy = [oY(1:new) oY(eSplit:end)];
            app.debugPlots.ff.Children(end).Children(3+addPs).XData = nSx;
            app.debugPlots.ff.Children(end).Children(3+addPs).YData = nSy;

            app.debugPlots.ff.Children(end).Children(4+addPs).XData = eSplit;
            app.debugPlots.ff.Children(end).Children(4+addPs).YData = oY(eSplit);

            [curve, goodness, output] = fit(nSx',nSy','smoothingspline'); %Fitting a spline to the smoothed and blanked artifact template
            xTemplate = oX;
            yTemplate = curve(oX)';

            yT = app.debugPlots.ff.Children(end).Children(end).YData;
            yT(new:eSplit) = yTemplate(new:eSplit);

            app.debugPlots.ff.Children(end).Children(1+addPs).XData = xTemplate;
            app.debugPlots.ff.Children(end).Children(1+addPs).YData = yT;
            app.temp2Use = yT;

            ZAP = ZapQuick_class(app.DAT);
            [app.ALLSTIM, app.ZAPPED] = ZAP.ZapBlock(app.idxs,yT,app.ChannelList.Value, [],[]);
            Z= reshape(app.ZAPPED',1,198000);
            AS = reshape(app.ALLSTIM',1,198000);
            app.debugPlots.first.Children(1).YData = Z;
        end

        function SliderChangeCallback2(app, event)
            app.debugPlots.s.Value = round(app.debugPlots.s.Value);
            app.debugPlots.txa.Value = num2str(app.debugPlots.s.Value);
            new = app.debugPlots.s.Value;

            o1 = app.debugPlots.ff.Children(end).Children(1).XData;

            idxs = app.idxs;
            chan = app.ChannelList.Value;
            D = app.DAT;

            o = D.StimBlocks(idxs).([chan,'_ArtifactTemplate_original']);
            if all(D.StimBlocks(idxs).([chan,'_ArtifactTemplate_t'])==0)
                temp2use2 = D.StimBlocks(idxs-1).([chan,'_ArtifactTemplate_used']);
            else
                temp2use2 = D.StimBlocks(idxs).([chan,'_ArtifactTemplate_t']);
            end

            v1 = max(o);
            v2 = max(temp2use2);
            mfactor = 1;%v1/v2;
            t = temp2use2*mfactor;

            %ll2+pe-1 = new

            mm1 = o(new);
            mm2 = temp2use2(new);
            if app.TemplateStyle == 3
                tt2 = temp2use2*((mm1/mm2));
                tt2(1:new-2) = o(1:new-2);
            elseif ismember({chan},'NerveCon')
                ptt=new;
                if ptt-50 < 1
                    mm1 = o(1:ptt);
                    mm2=temp2use2(1:ptt);
                else
                    mm1 = o(ptt-50:ptt);
                    mm2=temp2use2(ptt-50:ptt);
                end
                mdm = mm1./mm2;
                mdm(mdm<1) = [];
                mdm(mdm>mean(mdm)) = [];
                if isempty(mdm)
                    tt2=temp2use2;
                else
                    tt2=temp2use2*(mean(mdm));
                end
                [xi,yi] = polyxpoly(1:1000,tt2,1:1000,o);
                xi = round(xi);
                if app.TemplateStyle == 1
                    [mv, ml] = max(o);
                    pe = find(o==mv,1,'last')+1;
                elseif app.TemplateStyle == 2
                    if length(app.Positivepks)<2
                        pe = app.Positivepks(1)+1;
                    else
                        pe = app.Positivepks(2)+1;
                    end
                else
                    pe = app.Positivepks(1)+1;
                end
                cnrPt2use = xi(find(xi>pe,1,'first'));
                if cnrPt2use > ptt
                    tt2(1:ptt-15) = o(1:ptt-15);
                else
                    tt2(1:cnrPt2use) = o(1:cnrPt2use);
                end
            else
                tt2 = temp2use2*((mm1/mm2)-0.1);
                tt2(1:new-2) = o(1:new-2);
            end

            t = tt2;
            app.debugPlots.ff.Children(end).Children(3)
            app.debugPlots.ff.Children(end).Children(3).YData = tt2;
            app.debugPlots.ff.Children(end).Children(1).YData = o(new);
            app.debugPlots.ff.Children(end).Children(1).XData = new;

%             plot(debugPlots.ReartPoints,D.StimBlocks(idxs).([chan,'_ArtifactTemplate_original']))
%             hold(debugPlots.ReartPoints,'on')
%             plot(debugPlots.ReartPoints, temp2use2)
%             plot(debugPlots.ReartPoints, tt2)
%             plot(debugPlots.ReartPoints, 1:200,abs(o(1:200)-tt2(1:200)))
%             plot(debugPlots.ReartPoints, ll2+pe-1,o(ll2+pe-1),'b*')
%             hold(debugPlots.ReartPoints,'off')
%             debugPlots.ReartPoints.Title.String = 'ReSized Template Points';
%             drawnow

            app.temp2Use2 = t;

            ZAP = ZapQuick_class(app.DAT);
            [app.ALLSTIM3, app.ZAPPED3] = ZAP.ZapBlock(app.idxs,t,app.ChannelList.Value, [],[]);
            Z= reshape(app.ZAPPED3',1,198000);
            AS = reshape(app.ALLSTIM3',1,198000);
            app.debugPlots.third.Children(1).YData = Z;

        end

        function txtBxChangeCallback(app, event)
            app.debugPlots.s.Value = str2num(app.debugPlots.txa.Value{1});
        end

        function txtBxChangeCallback2(app, event)
            app.debugPlots.s2.Value = str2num(app.debugPlots.txa2.Value{1});
        end

        function continueBtnChangeCallback(app, event)
            uiresume(app.debugPlots.ff)
        end

        function binnedTempBtnChangeCallback(app, event)
            ZAP = ZapQuick_class(app.DAT);
            [response, noresponse] = ZAP.BinnedTemplate(app.idxs, app.ChannelList.Value, reshape(app.ZAPPED',1,198000), app.ALLSTIM, app.debugPlots, 0);
            app.noresponse = noresponse;
            [app.ALLSTIM2, app.ZAPPED2] = ZAP.ZapBlock(app.idxs,mean(noresponse)',app.ChannelList.Value);
            ALLSTIMPlot2 = reshape(app.ALLSTIM2',1,198000);
            ZAPPEDPlot2 = reshape(app.ZAPPED2',1,198000);
            app.debugPlots.second.Children(1).YData = ZAPPEDPlot2;
        end
    end

    methods (Access = private)

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function SURsuite_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>

            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to SURsuite (see VARARGIN)

            % Choose default command line output for SURsuite
            handles.output = hObject;
            % Update handles structure
            guidata(hObject, handles);
            maxY = app.ProgressBar.Position;
            axes(app.ProgressBar)
            app.figure1.WindowButtonDownFcn = [];
            app.PBarObj = rectangle(app.ProgressBar,'Position',[0,0,0,maxY(4)],'FaceColor','g');
            app.PBarTxt = text(app.ProgressBar,482,maxY(4)/2,['Waiting']);
        end

        % Value changed function: ChannelList
        function ChannelList_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to ChannelList (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            if contains(app.filedata.name,'.SUR')
                app.CurrentList.Enable = 'on';
%                 app.SubtractArtifact.Enable = 'on';
                app.APShapeDetButton.Enable = 'on';
            end
            hObject.BackgroundColor = 'g';
            guidata(hObject, handles);
        end

        % Button pushed function: InitialChannelTemps
        function InitialChannelTemps_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to InitialChannelTemps (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            hObject.BackgroundColor = 'g';
            TC = Template_class(app.DAT);
            channel = app.ChannelList.Value;
            if ~isprop(app.DAT,'APorientation')
                app.DAT.addprop('APorientation');
                app.DAT.APorientation = app.APorientation;
            end
            [DAGANtempl_unzeroed, DAGANtempl] = TC.GetAllArtifactTemplate(channel, app);

            [DAGANAPtempl_unzeroed, DAGANAPtempl] = TC.GetAllAPTemplate(channel, app);


            D = app.DAT;
            app.ProgressBar.Title.String = 'Saving Processed Data, Estimate 3min';
            app.PBarObj.Position(3) = 1000;
            app.PBarObj.FaceColor = 'y';
            drawnow
            save([app.SaveDirectory.Value,'\',D.fname,'.SUR'],'D')
            handles.StimList.Enable = 'on';
            handles.RefList.Enable = 'on';
            handles.CurrentList.Enable = 'on';
            handles.AnalyzeCombos.Enable = 'on';
            app.MotionStimList.Enable = 'on';
            app.PlotMotion.Enable = 'on';
            app.AnalyzeMotion.Enable = 'on';
            app.AlignSyncSigs.Enable = 'on';
            app.PBarObj.FaceColor = 'g';
            app.ProgressBar.Title.String = 'Data Saved';
            drawnow
            pause(0.5)
            app.ProgressBar.Title.String = 'Waiting';
            app.PBarObj.Position(3) = 0;
            drawnow
            guidata(hObject, handles);
            app.APShapeDetButton.Enable = 'on';
        end

        % Button pushed function: LoadFile
        function LoadFile_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to LoadFile (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            handles.ChannelList.Value = 1;
            handles.ChannelList.String = {'Listbox'};
            handles.ChannelList.BackgroundColor = [1 1 1];
            handles.InitialChannelTemps.BackgroundColor = [0.94 0.94 0.94];

            handles.StimList.Value = 1;
            handles.StimList.String = {'Listbox'};
            handles.RefList.Value = 1;
            handles.RefList.String = {'Listbox'};
            handles.CurrentList.Value = 1;
            handles.CurrentList.String = {'Listbox'};

            handles.MotionStimList.Value = 1;
            handles.MotionStimList.String = {'Listbox'};

            app.StimList.Enable = 'off';
            app.RefList.Enable = 'off';
            app.CurrentList.Enable = 'off';
            app.SubtractArtifact.Enable = 'off';
            app.APShapeDetButton.Enable = 'off';
            app.MotionStimList.Enable = 'off';
            app.PlotMotion.Enable = 'off';
            app.PlotElectrodeCombinations.Enable = 'off';
            app.APorientation = 0;
            if ~isempty(isgraphics(app.AnalysisPlotsTab.Children,'axes'))
                delete(app.AnalysisPlotsTab.Children(isgraphics(app.AnalysisPlotsTab.Children,'axes')))
            end
            if ~isempty(isgraphics(app.SpikeProbabilityPlot.Children,'axes'))
                delete(app.SpikeProbabilityPlot.Children(isgraphics(app.SpikeProbabilityPlot.Children,'axes')))
            end

            app.continueButton.Visible = 'off';
            app.KeepCEDSYNCButton.Visible = 'off';
            app.KeepFSYNCoButton.Visible = 'off';
            app.MotionSyncShorts.Visible = 'off';

            app.ReSizeTempButton.Visible = 'off';
            app.BinTempButton.Visible = 'off';
            app.SplineTempButton.Visible = 'off';
            app.stopButton.Visible = 'off';

            app.StartofAPButton.Visible = "off";
            app.MaxDepolarization1Button.Visible = "off";
            app.MaxDepolarization2Button.Visible = "off";
            app.MaxHyperpolarizationButton.Visible = "off";
            app.EndofAPButton.Visible = "off";

            app.StimList.BackgroundColor = [1 1 1];
            app.RefList.BackgroundColor = [1 1 1];
            app.CurrentList.BackgroundColor = [1 1 1];
            app.SubtractArtifact.BackgroundColor = [0.94 0.94 0.94];

            app.DebugFlgAll = 2;
            app.XYZAcc.Value = 0;
            app.XYZVel.Value = 0;
            app.LRZVel.Value = 0;
            cla(app.MotionHigh)
            cla(app.MotionMid)
            cla(app.MotionLow)
            cla(app.MotionNerveCon)
            app.TabGroup.SelectedTab = app.AnalysisPlotsTab;

            [file,path] = uigetfile({'*.smr;*.SUR', 'Single Unit Files (*.smr, *.SUR)'},'Select the Spike2 or Processed SUR File to Analyze');
            toU = isprop(app.figure1.Children,'enable');
            set(app.figure1.Children(toU),'Enable','off')
            if contains(file,'.SUR')
                app.filedata.name = file;
                app.filedata.path = path;
                app.SaveDirectory.Value = path(1:end-1);
                handles.FilePathText.String = [path,file];
                app.ProgressBar.Title.String = 'Loading Processed Data';
                app.PBarObj.Position(3) = 1000;
                app.PBarObj.FaceColor = 'y';
                drawnow
                load([path,file],'-mat');
                D.Stim_ticks = int64(D.Stim_ticks);
                app.DAT = D;
                for i = 1:length(app.DAT.CHANS)
                    if i == 1
                        app.ChannelList.Items = {app.DAT.CHANS{i}.Name};
                    end
                    app.ChannelList.Items = [app.ChannelList.Items {app.DAT.CHANS{i}.Name}];
                end


                if isprop(app.DAT,'MotionStim')
                    p5 = app.DAT.MotionStim.p5Hz.StimID;
                    o = app.DAT.MotionStim.oneHz.StimID;
                    t = app.DAT.MotionStim.twoHz.StimID;
                    if isfield(app.DAT.MotionStim,'NoseUp')
                        nu = app.DAT.MotionStim.NoseUp.StimID;
                    else
                        nu = [];
                    end
                    if isfield(app.DAT.MotionStim,'NoseDown')
                        nd = app.DAT.MotionStim.NoseDown.StimID;
                    else
                        nd = [];
                    end
                    if isfield(app.DAT.MotionStim,'LEarDown')
                        led = app.DAT.MotionStim.LEarDown.StimID;
                    else
                        led = [];
                    end
                    if isfield(app.DAT.MotionStim,'REarDown')
                        red = app.DAT.MotionStim.REarDown.StimID;
                    else
                        red = [];
                    end
                    temp = cell(1,length([p5' o' t' nu' nd' led' red']));
                    for i = 1:length([p5' o' t' nu' nd' led' red'])
                        switch i
                            case num2cell(p5)
                                toUse = find(p5==i);
                                temp{i} = [app.DAT.MotionStim.p5Hz.Direction{toUse},', 0.5 Hz, ',num2str(app.DAT.MotionStim.p5Hz.Magnitude(toUse)),' m/s, ',num2str(app.DAT.MotionStim.p5Hz.StimID(toUse))];
                            case num2cell(o)
                                toUse = find(o==i);
                                temp{i} = [app.DAT.MotionStim.oneHz.Direction{toUse},', 1 Hz, ',num2str(app.DAT.MotionStim.oneHz.Magnitude(toUse)),' m/s, ',num2str(app.DAT.MotionStim.oneHz.StimID(toUse))];

                            case num2cell(t)
                                toUse = find(t==i);
                                temp{i} = [app.DAT.MotionStim.twoHz.Direction{toUse},', 2 Hz, ',num2str(app.DAT.MotionStim.twoHz.Magnitude(toUse)),' m/s, ',num2str(app.DAT.MotionStim.twoHz.StimID(toUse))];
                            case num2cell(nu)
                                toUse = find(nu==i);
                                temp{i} = [app.DAT.MotionStim.NoseUp.Direction{toUse},', 20 Degrees, ',num2str(app.DAT.MotionStim.NoseUp.StimID(toUse))];
                            case num2cell(nd)
                                toUse = find(nd==i);
                                temp{i} = [app.DAT.MotionStim.NoseDown.Direction{toUse},', 20 Degrees, ',num2str(app.DAT.MotionStim.NoseDown.StimID(toUse))];
                            case num2cell(led)
                                toUse = find(led==i);
                                temp{i} = [app.DAT.MotionStim.LEarDown.Direction{toUse},', 20 Degrees, ',num2str(app.DAT.MotionStim.LEarDown.StimID(toUse))];
                            case num2cell(red)
                                toUse = find(red==i);
                                temp{i} = [app.DAT.MotionStim.REarDown.Direction{toUse},', 20 Degrees, ',num2str(app.DAT.MotionStim.REarDown.StimID(toUse))];
                        end
                    end
                    app.MotionStimList.Items = temp;
                    app.MotionStimList.Enable = 'on';
                    app.PlotMotion.Enable = 'on';
                end
                app.AlignSyncSigs.Enable = 'on';
                app.AnalyzeMotion.Enable = 'on';

                combs = [];
                for i = 1:length(app.DAT.StimBlocks)
                    
                    if app.DAT.StimBlocks(i).NStim > 100
                        if ~isfield(app.DAT.StimBlocks(i),'ToUse') || isempty(app.DAT.StimBlocks(i).ToUse)
                            s = app.DAT.StimBlocks(i).Stim_E;
                            r = app.DAT.StimBlocks(i).Ref_E;
                            c = app.DAT.StimBlocks(i).Current_uA;
                            if isempty(combs)
                                combs = [combs; [s r c]];
                                app.DAT.StimBlocks(i).ToUse = 1;
                            else
                                if any(ismember(combs,[s r c],'row'))
                                    idxsT = app.DAT.GetBlockFromParams(198, s, r, c, []);
                                    if length(idxsT)>1
                                        if length([app.DAT.StimBlocks(idxsT).ToUse]) < length(idxsT)
                                            txt = ['Duplicates for Stim ', num2str(s),', Ref ', num2str(r), ', At ',num2str(c),'uA were found. Please choose one to use (Stim Block #)'];
                                            answer = nbuttondlg(txt,cellfun(@num2str,num2cell(idxsT),'UniformOutput',false),'PromptTextHeight',50);
                                            app.DAT.StimBlocks(str2num(answer)).ToUse = 1;
                                            app.DAT.StimBlocks(idxsT(idxsT~=str2num(answer))).ToUse = 0;
                                        end
                                    end
                                else
                                    combs = [combs; [s r c]];
                                    app.DAT.StimBlocks(i).ToUse = 1;
                                end
                            end
                        end

                        if ismember(app.StimList.Items,'Listbox')
                            app.StimList.Items = {num2str(app.DAT.StimBlocks(i).Stim_E)};
                            app.RefList.Items = {num2str(app.DAT.StimBlocks(i).Ref_E)};
                            app.CurrentList.Items = {num2str(app.DAT.StimBlocks(i).Current_uA)};
                        else
                            if ~ismember(app.StimList.Items,num2str(app.DAT.StimBlocks(i).Stim_E))
                                app.StimList.Items = [app.StimList.Items {num2str(app.DAT.StimBlocks(i).Stim_E)}];
                            end

                            if ~ismember(app.RefList.Items,num2str(app.DAT.StimBlocks(i).Ref_E))
                                app.RefList.Items = [app.RefList.Items {num2str(app.DAT.StimBlocks(i).Ref_E)}];
                            end

                            if ~ismember(app.CurrentList.Items,num2str(app.DAT.StimBlocks(i).Current_uA))
                                app.CurrentList.Items = [app.CurrentList.Items {num2str(app.DAT.StimBlocks(i).Current_uA)}];
                            end
                        end
                    else
                        if isfield(app.DAT.StimBlocks(i),'ToUse')
                            if isempty(app.DAT.StimBlocks(i).ToUse)
                                app.DAT.StimBlocks(i).ToUse = 1;
                            end
                        else
                            app.DAT.StimBlocks(i).ToUse = 1;
                        end
                    end
                end

                if isprop(app.DAT,'APRefProperites')
                    app.DAT.addprop('APRefProperties')
                    app.DAT.APRefProperties = app.DAT.APRefProperites;
                    app.DAT.rmprop('APRefProperites')
                end

                app.PBarObj.FaceColor = 'g';
                app.ProgressBar.Title.String = 'Data Loaded';
                pause(0.5)
                app.ProgressBar.Title.String = 'Waiting';
                app.PBarObj.Position(3) = 0;
                firstOne = D.GetBlockFromParams(198, 1, 10, 20, 1);
                fs = fields(app.DAT.StimBlocks(firstOne));
                f2U = find(contains(fs,{'APCount'}));
                f2U2 = find(contains(fs,{'ZAPPED'}));

                app.StimList.Enable = 'on';
                app.RefList.Enable = 'on';
                app.CurrentList.Enable = 'on';


                app.SubtractArtifact.Enable = 'on';
                app.APShapeDetButton.Enable = 'on';


                if any(contains(fs,{'NerveCon'}))
                    app.ChannelList.Value = 'NerveCon';
                elseif any(contains(fs,{'ZapOut'}))
                    app.ChannelList.Value = 'ZapOut';
                else
                    handles.ChannelList.BackgroundColor = 'y';
                end


                if ~isempty(f2U) && ~isempty(f2U2)
                    if any([app.DAT.StimBlocks.(fs{f2U})]>0)
                        app.PlotElectrodeCombinations.Enable = 'on';
                        app.SubtractArtifact.Enable = 'on';
                        app.APShapeDetButton.Enable = "on";
                        app.FindAPs.Enable = 'on';
                    elseif ~isempty(f2U2) && any(~cellfun(@isempty,{app.DAT.StimBlocks.(fs{f2U2})}))
                        app.PlotElectrodeCombinations.Enable = 'on';
                        app.SubtractArtifact.Enable = 'on';
                        app.APShapeDetButton.Enable = "on";
                        app.FindAPs.Enable = 'on';
                    else
                        app.StimList.BackgroundColor = 'y';
                        app.RefList.BackgroundColor = 'y';
                        app.CurrentList.BackgroundColor = 'y';
                    end
                else
                    app.StimList.BackgroundColor = 'y';
                    app.RefList.BackgroundColor = 'y';
                    app.CurrentList.BackgroundColor = 'y';
                end

            else
                if isequal(file,0)
                    %                handles.filedata.name = '';
                    %                handles.filedata.path = '';
                    app.filedata.name = '';
                    app.filedata.path = '';
                else
                    app.filedata.name = file;
                    app.filedata.path = path;
                    sveDirName = [path,strrep(file,'.smr',''),'_AnalysisFiles'];
                    if ~isfolder(sveDirName)
                        mkdir(sveDirName);
                        app.SaveDirectory.Value = sveDirName;
                    else
                        app.SaveDirectory.Value = sveDirName;
                    end
                end


                handles.FilePathText.String = [path,file];
                cd(path);
                [DAT,Z, AZT]=QuickLoadBrian([path,file], app);
                app.APorientation = DAT.APorientation;
                app.DAT = DAT;
                handles.ChannelList.BackgroundColor = 'y';
                handles.InitialChannelTemps.BackgroundColor = 'y';
                guidata(hObject, handles);
            end
            set(app.figure1.Children(toU),'Enable','on')
        end

        % Button pushed function: PlotMotion
        function PlotMotionButtonPushed(app, event)

            cla(app.FigPlot)
            app.FigPlot.Visible = "off";
            app.TabGroup.Position = [449 17 842 549];
            app.StartofAPButton.Visible = "off";
            app.StartofAPButton.Value = 0;
            app.StartofAPButton.BackgroundColor = [0.96,0.96,0.96];
            app.MaxDepolarization1Button.Visible = "off";
            app.MaxDepolarization1Button.Value = 0;
            app.MaxDepolarization1Button.BackgroundColor = [0.96,0.96,0.96];
            app.MaxDepolarization2Button.Visible = "off";
            app.MaxDepolarization2Button.Value = 0;
            app.MaxDepolarization2Button.BackgroundColor = [0.96,0.96,0.96];
            app.MaxHyperpolarizationButton.Visible = "off";
            app.MaxHyperpolarizationButton.Value = 0;
            app.MaxHyperpolarizationButton.BackgroundColor = [0.96,0.96,0.96];
            app.MaxHyperpolarizationButton.FontColor = 'k';
            app.EndofAPButton.Visible = "off";
            app.EndofAPButton.Value = 0;
            app.EndofAPButton.BackgroundColor = [0.96,0.96,0.96];
            app.continueButton.Visible = "off";
            if ~isempty(app.TB)
                for i = 1:length(app.TB.Children)
                    if isprop(app.TB.Children(i),'Value')
                        app.TB.Children(i).Value = 'off';
                    end
                end
            end
            if contains(app.MotionStimList.Value,'0.5 Hz')
                sID = find(ismember(app.MotionStimList.Items,app.MotionStimList.Value));
                toUse = find(sID==app.DAT.MotionStim.p5Hz.StimID);
                app.motionTime = app.DAT.MotionStim.p5Hz.MotionTime(toUse,:);
                app.larp = app.DAT.MotionStim.p5Hz.LARP(toUse,:);
                app.ralp = app.DAT.MotionStim.p5Hz.RALP(toUse,:);
                app.x = app.DAT.MotionStim.p5Hz.XVel.Data(toUse,:);
                app.y = app.DAT.MotionStim.p5Hz.YVel.Data(toUse,:);
                app.z = app.DAT.MotionStim.p5Hz.ZVel.Data(toUse,:);
                app.xA = app.DAT.MotionStim.p5Hz.XAcc.Data(toUse,:);
                app.yA = app.DAT.MotionStim.p5Hz.YAcc.Data(toUse,:);
                app.zA = app.DAT.MotionStim.p5Hz.ZAcc.Data(toUse,:);
                app.IsR = app.DAT.MotionStim.p5Hz.APSIsR{toUse};
                app.IsRt = app.DAT.MotionStim.p5Hz.APSIsRt{toUse};
            elseif contains(app.MotionStimList.Value,'1 Hz')
                sID = find(ismember(app.MotionStimList.Items,app.MotionStimList.Value));
                toUse = find(sID==app.DAT.MotionStim.oneHz.StimID);
                app.motionTime = app.DAT.MotionStim.oneHz.MotionTime(toUse,:);
                app.larp = app.DAT.MotionStim.oneHz.LARP(toUse,:);
                app.ralp = app.DAT.MotionStim.oneHz.RALP(toUse,:);
                app.x = app.DAT.MotionStim.oneHz.XVel.Data(toUse,:);
                app.y = app.DAT.MotionStim.oneHz.YVel.Data(toUse,:);
                app.z = app.DAT.MotionStim.oneHz.ZVel.Data(toUse,:);
                app.xA = app.DAT.MotionStim.oneHz.XAcc.Data(toUse,:);
                app.yA = app.DAT.MotionStim.oneHz.YAcc.Data(toUse,:);
                app.zA = app.DAT.MotionStim.oneHz.ZAcc.Data(toUse,:);
                app.IsR = app.DAT.MotionStim.oneHz.APSIsR{toUse};
                app.IsRt = app.DAT.MotionStim.oneHz.APSIsRt{toUse};
            elseif contains(app.MotionStimList.Value,'2 Hz')
                sID = find(ismember(app.MotionStimList.Items,app.MotionStimList.Value));
                toUse = find(sID==app.DAT.MotionStim.twoHz.StimID);
                app.motionTime = app.DAT.MotionStim.twoHz.MotionTime(toUse,:);
                app.larp = app.DAT.MotionStim.twoHz.LARP(toUse,:);
                app.ralp = app.DAT.MotionStim.twoHz.RALP(toUse,:);
                app.x = app.DAT.MotionStim.twoHz.XVel.Data(toUse,:);
                app.y = app.DAT.MotionStim.twoHz.YVel.Data(toUse,:);
                app.z = app.DAT.MotionStim.twoHz.ZVel.Data(toUse,:);
                app.xA = app.DAT.MotionStim.twoHz.XAcc.Data(toUse,:);
                app.yA = app.DAT.MotionStim.twoHz.YAcc.Data(toUse,:);
                app.zA = app.DAT.MotionStim.twoHz.ZAcc.Data(toUse,:);
                app.IsR = app.DAT.MotionStim.twoHz.APSIsR{toUse};
                app.IsRt = app.DAT.MotionStim.twoHz.APSIsRt{toUse};
            elseif contains(app.MotionStimList.Value,'Nose Down')
                sID = find(ismember(app.MotionStimList.Items,app.MotionStimList.Value));
                toUse = find(sID==app.DAT.MotionStim.NoseDown.StimID);
                app.motionTime = app.DAT.MotionStim.NoseDown.MotionTime(toUse,:);
                app.larp = app.DAT.MotionStim.NoseDown.LARP(toUse,:);
                app.ralp = app.DAT.MotionStim.NoseDown.RALP(toUse,:);
                app.x = app.DAT.MotionStim.NoseDown.XVel.Data(toUse,:);
                app.y = app.DAT.MotionStim.NoseDown.YVel.Data(toUse,:);
                app.z = app.DAT.MotionStim.NoseDown.ZVel.Data(toUse,:);
                app.xA = app.DAT.MotionStim.NoseDown.XAcc.Data(toUse,:);
                app.yA = app.DAT.MotionStim.NoseDown.YAcc.Data(toUse,:);
                app.zA = app.DAT.MotionStim.NoseDown.ZAcc.Data(toUse,:);
                app.IsR = app.DAT.MotionStim.NoseDown.APSIsR{toUse};
                app.IsRt = app.DAT.MotionStim.NoseDown.APSIsRt{toUse};
            elseif contains(app.MotionStimList.Value,'Nose Up')
                sID = find(ismember(app.MotionStimList.Items,app.MotionStimList.Value));
                toUse = find(sID==app.DAT.MotionStim.NoseUp.StimID);
                app.motionTime = app.DAT.MotionStim.NoseUp.MotionTime(toUse,:);
                app.larp = app.DAT.MotionStim.NoseUp.LARP(toUse,:);
                app.ralp = app.DAT.MotionStim.NoseUp.RALP(toUse,:);
                app.x = app.DAT.MotionStim.NoseUp.XVel.Data(toUse,:);
                app.y = app.DAT.MotionStim.NoseUp.YVel.Data(toUse,:);
                app.z = app.DAT.MotionStim.NoseUp.ZVel.Data(toUse,:);
                app.xA = app.DAT.MotionStim.NoseUp.XAcc.Data(toUse,:);
                app.yA = app.DAT.MotionStim.NoseUp.YAcc.Data(toUse,:);
                app.zA = app.DAT.MotionStim.NoseUp.ZAcc.Data(toUse,:);
                app.IsR = app.DAT.MotionStim.NoseUp.APSIsR{toUse};
                app.IsRt = app.DAT.MotionStim.NoseUp.APSIsRt{toUse};
            elseif contains(app.MotionStimList.Value,'Right Ear')
                sID = find(ismember(app.MotionStimList.Items,app.MotionStimList.Value));
                toUse = find(sID==app.DAT.MotionStim.REarDown.StimID);
                app.motionTime = app.DAT.MotionStim.REarDown.MotionTime(toUse,:);
                app.larp = app.DAT.MotionStim.REarDown.LARP(toUse,:);
                app.ralp = app.DAT.MotionStim.REarDown.RALP(toUse,:);
                app.x = app.DAT.MotionStim.REarDown.XVel.Data(toUse,:);
                app.y = app.DAT.MotionStim.REarDown.YVel.Data(toUse,:);
                app.z = app.DAT.MotionStim.REarDown.ZVel.Data(toUse,:);
                app.xA = app.DAT.MotionStim.REarDown.XAcc.Data(toUse,:);
                app.yA = app.DAT.MotionStim.REarDown.YAcc.Data(toUse,:);
                app.zA = app.DAT.MotionStim.REarDown.ZAcc.Data(toUse,:);
                app.IsR = app.DAT.MotionStim.REarDown.APSIsR{toUse};
                app.IsRt = app.DAT.MotionStim.REarDown.APSIsRt{toUse};
            elseif contains(app.MotionStimList.Value,'Left Ear')
                sID = find(ismember(app.MotionStimList.Items,app.MotionStimList.Value));
                toUse = find(sID==app.DAT.MotionStim.LEarDown.StimID);
                app.motionTime = app.DAT.MotionStim.LEarDown.MotionTime(toUse,:);
                app.larp = app.DAT.MotionStim.LEarDown.LARP(toUse,:);
                app.ralp = app.DAT.MotionStim.LEarDown.RALP(toUse,:);
                app.x = app.DAT.MotionStim.LEarDown.XVel.Data(toUse,:);
                app.y = app.DAT.MotionStim.LEarDown.YVel.Data(toUse,:);
                app.z = app.DAT.MotionStim.LEarDown.ZVel.Data(toUse,:);
                app.xA = app.DAT.MotionStim.LEarDown.XAcc.Data(toUse,:);
                app.yA = app.DAT.MotionStim.LEarDown.YAcc.Data(toUse,:);
                app.zA = app.DAT.MotionStim.LEarDown.ZAcc.Data(toUse,:);
                app.IsR = app.DAT.MotionStim.LEarDown.APSIsR{toUse};
                app.IsRt = app.DAT.MotionStim.LEarDown.APSIsRt{toUse};
            end
            app.TabGroup.SelectedTab = app.MotionStimPlotsTab;
            if contains(app.MotionStimList.Value,[{'Surge'} {'Lateral'} {'Heave'} {'Nose'} {'Ear'}])
                app.XYZAcc.Value = 1;
                app.XYZVel.Value = 0;
                app.LRZVel.Value = 0;
            else
                app.XYZAcc.Value = 0;
                app.XYZVel.Value = 0;
                app.LRZVel.Value = 1;
            end
            app.ChangeMotionStimPlots
        end

        % Value changed function: XYZAcc
        function XYZAccValueChanged(app, event)
            value = app.XYZAcc.Value;
            if value
                app.LRZVel.Value = 0;
                app.XYZVel.Value = 0;
                app.ChangeMotionStimPlots;
            end
        end

        % Value changed function: LRZVel
        function LRZVelValueChanged(app, event)
            value = app.LRZVel.Value;
            if value
                app.XYZAcc.Value = 0;
                app.XYZVel.Value = 0;
                app.ChangeMotionStimPlots;
            end
        end

        % Value changed function: XYZVel
        function XYZVelValueChanged(app, event)
            value = app.XYZVel.Value;
            if value
                app.LRZVel.Value = 0;
                app.XYZAcc.Value = 0;
                app.ChangeMotionStimPlots;
            end
        end

        % Button pushed function: SaveMotionStimFig
        function SaveMotionStimFigButtonPushed(app, event)
            app.SaveMotionStimFig.BackgroundColor = 'y';
            f = figure('units','normalized','outerposition',[0 0 1 1]);
            f.Visible = 'off';
            u = subtightplot(4,1,1,[0.04 0.000000000005],[0.125 0.075],[.05 .05],'Parent', f);

            plot(u,app.MotionHigh.Children(1).XData-app.MotionHigh.Children(1).XData(1),app.MotionHigh.Children(1).YData,'k','LineWidth',1.5)
            u.YLabel.String = app.MotionHigh.YLabel.String;
            u.XTickLabel = [];
            u.Title.String = app.MotionHigh.Title.String;

            m = subtightplot(4,1,2,[0.04 0.000000000005],[0.10 0.075],[.05 .05],'Parent', f);

            plot(m,app.MotionMid.Children(1).XData-app.MotionMid.Children(1).XData(1),app.MotionMid.Children(1).YData,'k','LineWidth',1.5)
            m.YLabel.String = app.MotionHigh.YLabel.String;
            m.XTickLabel = [];
            m.Title.String = app.MotionMid.Title.String;

            l = subtightplot(4,1,3,[0.04 0.000000000005],[0.10 0.075],[.05 .05],'Parent', f);

            plot(l,app.MotionLow.Children(1).XData-app.MotionLow.Children(1).XData(1),app.MotionLow.Children(1).YData,'k','LineWidth',1.5)
            l.YLabel.String = app.MotionHigh.YLabel.String;
            l.XTickLabel = [];
            l.Title.String = app.MotionLow.Title.String;

            mn = subtightplot(4,1,4,[0.04 0.000000000005],[0.10 0.075],[.05 .05],'Parent', f);
            plot(mn,app.MotionNerveCon.Children(1).XData-app.MotionNerveCon.Children(1).XData(1),app.MotionNerveCon.Children(1).YData,'k.','MarkerSize',10)
            mn.YLabel.String = app.MotionNerveCon.YLabel.String;
            mn.XLabel.String = 'Time (s)';
            mn.Title.String = 'Instantaneous Spike Rate';

            sameaxes([],[u, m, l]);


            name = app.MotionStimList.Value;
            cs = find(name==',');
            name(cs(2):end) = '';

            sgtitle(f,{name;''},'FontSize', 22, 'FontWeight', 'Bold');
            set(findobj(f,'type','axes'),'FontSize',15,'LineWidth', 2);
            sname = strrep(name,',','');
            sname = strrep(sname,' ','');
            saveas(f,[app.SaveDirectory.Value,'\',sname,'.svg']);
            saveas(f,[app.SaveDirectory.Value,'\',sname,'.jpg']);
            saveas(f,[app.SaveDirectory.Value,'\',sname,'.fig']);
            close(f)
            app.SaveMotionStimFig.BackgroundColor = 'g';
            pause(.3)
            app.SaveMotionStimFig.BackgroundColor = [0.96,0.96,0.96];
        end

        % Button pushed function: SubtractArtifact
        function SubtractArtifactButtonPushed(app, event)
            cla(app.FigPlot)
            app.FigPlot.Visible = "off";
            app.TabGroup.Position = [449 17 842 549];
            app.StartofAPButton.Visible = "off";
            app.StartofAPButton.Value = 0;
            app.StartofAPButton.BackgroundColor = [0.96,0.96,0.96];
            app.MaxDepolarization1Button.Visible = "off";
            app.MaxDepolarization1Button.Value = 0;
            app.MaxDepolarization1Button.BackgroundColor = [0.96,0.96,0.96];
            app.MaxDepolarization2Button.Visible = "off";
            app.MaxDepolarization2Button.Value = 0;
            app.MaxDepolarization2Button.BackgroundColor = [0.96,0.96,0.96];
            app.MaxHyperpolarizationButton.Visible = "off";
            app.MaxHyperpolarizationButton.Value = 0;
            app.MaxHyperpolarizationButton.BackgroundColor = [0.96,0.96,0.96];
            app.MaxHyperpolarizationButton.FontColor = 'k';
            app.EndofAPButton.Visible = "off";
            app.EndofAPButton.Value = 0;
            app.EndofAPButton.BackgroundColor = [0.96,0.96,0.96];
            app.continueButton.Visible = "off";
            if ~isempty(app.TB)
                for i = 1:length(app.TB.Children)
                    if isprop(app.TB.Children(i),'Value')
                        app.TB.Children(i).Value = 'off';
                    end
                end
            end
            focus(app.figure1)
            datacursormode(app.figure1,'off')
            app.TabGroup.SelectedTab = app.AnalysisPlotsTab;
            app.figure1.CurrentObject = app.figure1;
            app.FigPlot.Visible = 'off';
            app.APInstructions.Visible = "off";
            app.ArtAnalysisInstructions.Visible = 'on';
            app.PlotRawDataSwitch.Visible = "off";
            app.PlotRawDataSwitchLabel.Visible = 'off';
            app.TabGroup.Position = [449 17 842 549];
            app.ChangeProgressBar([],[],[],1)
            app.KeyPressed = 0;
            if ~isempty(isgraphics(app.AnalysisPlotsTab.Children,'axes'))
                delete(app.AnalysisPlotsTab.Children(isgraphics(app.AnalysisPlotsTab.Children,'axes')))
            end
            app.SplineTempButton.Visible = 'on';
            app.BinTempButton.Visible = 'on';
            app.ReSizeTempButton.Visible = 'on';
            app.continueButton.Visible = 'on';
            app.stopButton.Visible = 'on';
            app.stopButton.Value = 0;
            app.ArtRemoveShorts.Visible = 'on';

            if app.StimList.BackgroundColor == 'g'
                app.StimList.BackgroundColor = [1 1 1];
                app.RefList.BackgroundColor = [1 1 1];
                app.CurrentList.BackgroundColor = [1 1 1];
                app.SubtractArtifact.BackgroundColor = [0.94 0.94 0.94];
            end
            NStim = 198;
            StimE =[];
            for i = 1:length(app.StimList.Value)
                StimE = [StimE str2num(app.StimList.Value{i})];
            end
            RefE = [];
            for i = 1:length(app.RefList.Value)
                RefE = [RefE str2num(app.RefList.Value{i})];
            end
            StimCur = [];
            for i = 1:length(app.CurrentList.Value)
                StimCur = [StimCur str2num(app.CurrentList.Value{i})];
            end

            idxss = app.DAT.GetBlockFromParams(NStim, StimE, RefE, StimCur, []);
            APC = ActionPotential_class(app.DAT);
            TC = Template_class(app.DAT);
            ZAP = ZapQuick_class(app.DAT);
            if app.DebugFlgAll > 1
%                 answer = nbuttondlg('Would you like to show the debug plots for Artifact Subtraction',{'Yes','No','Yes To All','No To All'},'DefaultButton',4,'PromptTextHeight',50);
%                 switch answer
%                     case 'Yes'
%                         app.DebugFlgSingle = 1;
%                         app.DebugFlgAll = 2;
%                         debugFlg = 1;
%                         showFindFlg = 1;
%                     case 'No'
%                         app.DebugFlgSingle = 0;
%                         app.DebugFlgAll = 2;
%                         debugFlg = 0;
%                         showFindFlg = 0;
%                     case 'Yes To All'
%                         app.DebugFlgSingle = 0;
%                         app.DebugFlgAll = 1;
%                         debugFlg = 1;
%                         showFindFlg = 1;
%                     case 'No To All'
%                         app.DebugFlgSingle = 0;
%                         app.DebugFlgAll = 0;
%                         debugFlg = 0;
%                         showFindFlg = 0;
%                 end
                app.DebugFlgSingle = 0;
                        app.DebugFlgAll = 2;
                        debugFlg = 0;
                        showFindFlg = 0;
            elseif app.DebugFlgAll == 1
                debugFlg = 1;
            else
                debugFlg = 0;
                showFindFlg = 0;
            end
            datacursormode(app.figure1,'off')
            chan = app.ChannelList.Value;
            ZAP.ClearArtifact(chan, idxss, app)
            ZAP.SubtractArtifact(chan, idxss, APC, TC, app, debugFlg, showFindFlg)
            app.ChangeProgressBar([],[],[],1)
            if ~isempty(isgraphics(app.AnalysisPlotsTab.Children,'axes'))
                delete(app.AnalysisPlotsTab.Children(isgraphics(app.AnalysisPlotsTab.Children,'axes')))
            end
            app.SplineTempButton.Visible = 'off';
            app.BinTempButton.Visible = 'off';
            app.ReSizeTempButton.Visible = 'off';
            app.continueButton.Visible = 'off';
            app.stopButton.Visible = 'off';
            app.stopButton.Value = 0;
            app.ArtRemoveShorts.Visible = 'off';
            app.FindAPs.Enable = 'on';
            app.ArtAnalysisInstructions.Visible = 'off';
        end

        % Button pushed function: PlotElectrodeCombinations
        function PlotElectrodeCombinationsButtonPushed(app, event)
            cla(app.FigPlot)
            app.FigPlot.Visible = "off";
            app.TabGroup.Position = [449 17 842 549];
            app.StartofAPButton.Visible = "off";
            app.StartofAPButton.Value = 0;
            app.StartofAPButton.BackgroundColor = [0.96,0.96,0.96];
            app.MaxDepolarization1Button.Visible = "off";
            app.MaxDepolarization1Button.Value = 0;
            app.MaxDepolarization1Button.BackgroundColor = [0.96,0.96,0.96];
            app.MaxDepolarization2Button.Visible = "off";
            app.MaxDepolarization2Button.Value = 0;
            app.MaxDepolarization2Button.BackgroundColor = [0.96,0.96,0.96];
            app.MaxHyperpolarizationButton.Visible = "off";
            app.MaxHyperpolarizationButton.Value = 0;
            app.MaxHyperpolarizationButton.BackgroundColor = [0.96,0.96,0.96];
            app.MaxHyperpolarizationButton.FontColor = 'k';
            app.EndofAPButton.Visible = "off";
            app.EndofAPButton.Value = 0;
            app.EndofAPButton.BackgroundColor = [0.96,0.96,0.96];
            app.continueButton.Visible = "off";
            if ~isempty(app.TB)
                for i = 1:length(app.TB.Children)
                    if isprop(app.TB.Children(i),'Value')
                        app.TB.Children(i).Value = 'off';
                    end
                end
            end
            app.TabGroup.SelectedTab = app.SpikeProbabilityPlot;
            app.ArtAnalysisInstructions.Visible = 'off';
            if ~isempty(isgraphics(app.SpikeProbabilityPlot.Children,'axes'))
                delete(app.SpikeProbabilityPlot.Children(isgraphics(app.SpikeProbabilityPlot.Children,'axes')))
            end
            D = app.DAT;
            ax = axes(app.SpikeProbabilityPlot);
            x = [];
            y = [];
            impPlot = [];
            ldgName = [];
            ordVct = 1;
            NStim = 198;
            StimE =[];
            for i = 1:length(app.StimList.Value)
                StimE = [StimE str2num(app.StimList.Value{i})];
            end
            RefE = [];
            for i = 1:length(app.RefList.Value)
                RefE = [RefE str2num(app.RefList.Value{i})];
            end
            StimCur = [];
            for i = 1:length(app.CurrentList.Value)
                StimCur = [StimCur str2num(app.CurrentList.Value{i})];
            end

            idxss = app.DAT.GetBlockFromParams(NStim, StimE, RefE, StimCur, 1);
            ordr = zeros(1,length(idxss)/5);
            chan = app.ChannelList.Value;
            
            for i = 1:length(idxss)
                s = D.StimBlocks(idxss(i)).Stim_E;
                refn = '';
                switch s
                    case 1
                        colorPlot = [0 0 1];
                        mk = 'o';
                        n = 'LP, Shallow';
                        ordrV = 1;
                        lnst = '-';
                    case 2
                        colorPlot = [0 0 1];
                        mk = '*';
                        n = 'LP, Middle';
                        ordrV = 2;
                        lnst = '-';
                    case 3
                        colorPlot = [0 0 1];
                        mk = 'v';
                        n = 'LP, Deep';
                        ordrV = 3;
                        lnst = '-';
                    case 4
                        colorPlot = [0 .74 0];
                        mk = 'o';
                        n = 'LA, Shallow';
                        ordrV = 4;
                        lnst = '-';
                    case 5
                        colorPlot = [0 .74 0];
                        mk = '*';
                        n = 'LA, Middle Outside';
                        ordrV = 5;
                        lnst = '-';
                    case 6
                        colorPlot = [0 .74 0];
                        mk = 'v';
                        n = 'LA, Deep';
                        ordrV = 7;
                        lnst = '-';
                    case 7
                        colorPlot = [1 0 0];
                        mk = 'v';
                        n = 'LH, Deep';
                        ordrV = 11;
                        lnst = '-';
                    case 8
                        colorPlot = [1 0 0];
                        mk = '*';
                        n = 'LH, Middle Outside';
                        ordrV = 9;
                        lnst = '-';
                    case 9
                        colorPlot = [1 0 0];
                        mk = 'o';
                        n = 'LH, Shallow';
                        ordrV = 8;
                        lnst = '-';
                    case 14
                        colorPlot = [0 .74 0];
                        mk = 's';
                        n = 'LA, Middle Inside';
                        ordrV = 6;
                        lnst = '-';
                    case 15
                        colorPlot = [1 0 0];
                        mk = 's';
                        n = 'LH, Middle Inside';
                        ordrV = 10;
                        lnst = '-';
                end
                r = D.StimBlocks(idxss(i)).Ref_E;
                switch r
                    case 1
                        colorPlot = [0 0 1];
                        n = strrep(n,'LP, ','');
                        n = [n, ' vs Shallow'];
                        refn = 'LP, Bipolar';
                        ordrV = 1;
                        lnst = '-';
                        skip = 0;
                    case 2
                        colorPlot = [0 0 1];
                        n = strrep(n,'LP, ','');
                        n = [n, ' vs Middle'];
                        refn = 'LP, Bipolar';
                        ordrV = 2;
                        lnst = '--';
                        skip = 0;
                    case 3
                        colorPlot = [0 0 1];
                        n = strrep(n,'LP, ','');
                        n = [n, ' vs Deep'];
                        refn = 'LP, Bipolar';
                        ordrV = 3;
                        lnst = ':';
                        skip = 0;
                    case 4
                        colorPlot = [0 .74 0];
                        n = strrep(n,'LA, ','');
                        n = [n, ' vs Shallow'];
                        refn = 'LA, Bipolar';
                        ordrV = 4;
                        lnst = '-';
                        skip = 0;
                    case 5
                        colorPlot = [0 .74 0];
                        n = strrep(n,'LA, ','');
                        n = [n, ' vs Middle Outside'];
                        refn = 'LA, Bipolar';
                        ordrV = 5;
                        lnst = '--';
                        skip = 0;
                    case 6
                        colorPlot = [0 .74 0];
                        n = strrep(n,'LA, ','');
                        n = [n, ' vs Deep'];
                        refn = 'LA, Bipolar';
                        ordrV = 7;
                        lnst = ':';
                        skip = 0;
                    case 7
                        colorPlot = [1 0 0];
                        n = strrep(n,'LH, ','');
                        n = [n, ' vs Deep'];
                        refn = 'LH, Bipolar';
                        ordrV = 11;
                        lnst = ':';
                        skip = 0;
                    case 8
                        colorPlot = [1 0 0];
                        n = strrep(n,'LH, ','');
                        n = [n, ' vs Middle Outside'];
                        refn = 'LH, Bipolar';
                        ordrV = 9;
                        lnst = '--';
                        skip = 0;
                    case 9
                        colorPlot = [1 0 0];
                        n = strrep(n,'LH, ','');
                        n = [n, ' vs Shallow'];
                        refn = 'LH, Bipolar';
                        ordrV = 8;
                        lnst = '-';
                        skip = 0;
                    case 14
                        colorPlot = [0 .74 0];
                        n = strrep(n,'LA, ','');
                        n = [n, ' vs Middle Inside'];
                        refn = 'LA, Bipolar';
                        ordrV = 6;
                        lnst = '-.';
                        skip = 0;
                    case 15
                        colorPlot = [1 0 0];
                        n = strrep(n,'LH, ','');
                        n = [n, ' vs Middle Inside'];
                        refn = 'LH, Bipolar';
                        ordrV = 10;
                        lnst = '-.';
                    case 10
                        skip = 0;
                        refn = 'Distant';
                    case 11
                        skip = 0;
                        refn = 'Deep CC';
                    case 12
                        skip = 0;
                        refn = 'Middle CC';
                    case 13
                        skip = 0;
                        refn = 'Shallow CC';
                end
                if ~skip
                    hold(ax,'on')
                    amp = D.StimBlocks(idxss(i)).Current_uA;
                    NumAPs = D.StimBlocks(idxss(i)).([chan,'_APCount'])/198;
                    if amp<200
                        x = [x amp];
                        y = [y NumAPs];
                    else
                        x = [x amp];
                        y = [y NumAPs];
                        if isempty(ldgName)
                            impPlot = [impPlot line(ax,x,y,'color',colorPlot,'LineWidth',2,'Marker',mk,'MarkerSize',10,'LineStyle',lnst)];%'LineWidth',3,'Marker',mk,'MarkerSize',18
                            ldgName = [ldgName {n}];
                            ordr(ordrV) = ordVct;
                            ordVct = ordVct+1;
                        elseif ~any(ismember(ldgName,{['Stim ',num2str(s)]}))
                            impPlot = [impPlot line(ax,x,y,'color',colorPlot,'LineWidth',2,'Marker',mk,'MarkerSize',10,'LineStyle',lnst)];
                            ldgName = [ldgName {n}];
                            ordr(ordrV) = ordVct;
                            ordVct = ordVct+1;
                        else
                            line(ax,x,y,'color',colorPlot,'LineWidth',2,'Marker',mk,'MarkerSize',10,'LineStyle',lnst)
                        end
                        x = [];
                        y = [];
                    end
                    hold(ax,'off')
                end
            end

            if any(contains(ldgName,'vs'))
                ordr = 1:length(idxss)/5;
            end
            [L,icons,plots,txt] = legend(impPlot(ordr),ldgName(ordr));
            warning('off','MATLAB:handle_graphics:exceptions:SceneNode');
            L.Position = [0.1680    0.4893    0.1397    0.4170];
%             L.Position(1) = [0.1680];
%             L.Position(2) = [0.4893];%L.Position = [0.1680    0.4893    0.1397    0.4170];
            ax.XGrid = 'on';
            ax.YGrid = 'on';
            ax.FontSize = 15;%30
            ax.LineWidth = 2;
            ax.XLabel.String = 'Stimulus Current (uA)';
            ax.YLabel.String = 'Spike Probability';
            ax.Title.String = ['Effect of Stimulation on Spike Probability By Stimulating Electrode, ',refn];
            L.FontSize = 5;%20
            for i = 1:length(icons)
                if contains(icons(i).Type,'text')
                    icons(i).FontSize = 10;
                end
            end
        end

        % Value changed function: StimList
        function StimListValueChanged(app, event)
            value = app.StimList.Value;
            y = [1 1 0];
            g = [0 1 0];
            if all(app.StimList.BackgroundColor == y)
                app.StimList.BackgroundColor = 'g';
            end
            if (all(app.StimList.BackgroundColor == g)) && (all(app.RefList.BackgroundColor == g)) && (all(app.CurrentList.BackgroundColor == g))
                app.SubtractArtifact.Enable = 'on';
                app.SubtractArtifact.BackgroundColor = 'g';
            end
        end

        % Value changed function: RefList
        function RefListValueChanged(app, event)
            value = app.RefList.Value;
            y = [1 1 0];
            g = [0 1 0];
            if all(app.RefList.BackgroundColor == y)
                app.RefList.BackgroundColor = 'g';
            end
            if (all(app.StimList.BackgroundColor == g)) && (all(app.RefList.BackgroundColor == g)) && (all(app.CurrentList.BackgroundColor == g))
                app.SubtractArtifact.Enable = 'on';
                app.SubtractArtifact.BackgroundColor = 'g';
            end
        end

        % Value changed function: CurrentList
        function CurrentListValueChanged(app, event)
            value = app.CurrentList.Value;
            y = [1 1 0];
            g = [0 1 0];
            if all(app.CurrentList.BackgroundColor == y)
                app.CurrentList.BackgroundColor = 'g';
            end
            if (all(app.StimList.BackgroundColor == g)) && (all(app.RefList.BackgroundColor == g)) && (all(app.CurrentList.BackgroundColor == g))
                app.SubtractArtifact.Enable = 'on';
                app.SubtractArtifact.BackgroundColor = 'g';
            end
        end

        % Button pushed function: continueButton
        function continueButtonPushed(app, event)
            uiresume(app.figure1)
            app.KeyPressed = 0;
        end

        % Button pushed function: APShapeDetButton
        function APShapeDetButtonPushed(app, event)
            cla(app.FigPlot)
            app.APLocPlot = [];
            app.FigPlot.Visible = "off";
            app.TabGroup.Position = [449 17 842 549];
            app.StartofAPButton.Visible = "off";
            app.StartofAPButton.Value = 0;
            app.StartofAPButton.BackgroundColor = [0.96,0.96,0.96];
            app.MaxDepolarization1Button.Visible = "off";
            app.MaxDepolarization1Button.Value = 0;
            app.MaxDepolarization1Button.BackgroundColor = [0.96,0.96,0.96];
            app.MaxDepolarization2Button.Visible = "off";
            app.MaxDepolarization2Button.Value = 0;
            app.MaxDepolarization2Button.BackgroundColor = [0.96,0.96,0.96];
            app.MaxHyperpolarizationButton.Visible = "off";
            app.MaxHyperpolarizationButton.Value = 0;
            app.MaxHyperpolarizationButton.BackgroundColor = [0.96,0.96,0.96];
            app.MaxHyperpolarizationButton.FontColor = 'k';
            app.EndofAPButton.Visible = "off";
            app.EndofAPButton.Value = 0;
            app.EndofAPButton.BackgroundColor = [0.96,0.96,0.96];
            app.continueButton.Visible = "off";
            if ~isempty(app.TB)
                for i = 1:length(app.TB.Children)
                    if isprop(app.TB.Children(i),'Value')
                        app.TB.Children(i).Value = 'off';
                    end
                end
            end
            app.mouseIsDown = false;
            app.TabGroup.SelectedTab = app.AnalysisPlotsTab;
            app.TabGroup.Position = [449 496 842 70];
            app.FigPlot.Visible = 'on';
            app.TB = axtoolbar(app.FigPlot,{'pan','zoomin','zoomout','restoreview'});
            cla(app.FigPlot)
            if ~isempty(isgraphics(app.AnalysisPlotsTab.Children,'axes'))
                delete(app.AnalysisPlotsTab.Children(isgraphics(app.AnalysisPlotsTab.Children,'axes')))
            end
            app.StartofAPButton.Visible = "on";
            app.MaxDepolarization1Button.Visible = "on";
            app.MaxDepolarization2Button.Visible = "on";
            app.MaxHyperpolarizationButton.Visible = "on";
            app.EndofAPButton.Visible = "on";
            app.continueButton.Visible = "on";
            app.ArtAnalysisInstructions.Visible = 'off';
%             NStim = 198;
%             StimE =[];
%             for i = 1:length(app.StimList.Value)
%                 StimE = [StimE str2num(app.StimList.Value{i})];
%             end
%             RefE = [];
%             for i = 1:length(app.RefList.Value)
%                 RefE = [RefE str2num(app.RefList.Value{i})];
%             end
%             StimCur = [];
%             for i = 1:length(app.CurrentList.Value)
%                 StimCur = [StimCur str2num(app.CurrentList.Value{i})];
%             end
            chan = app.ChannelList.Value;
% 
%             idxss = app.DAT.GetBlockFromParams(NStim, StimE, RefE, StimCur);
            temp2Use = [];
            for i = 1:length(app.DAT.StimBlocks) 
                temp2Use = [temp2Use; app.DAT.StimBlocks(i).([chan,'_APTemplate']).AVG_RAW'];
            end
%             temp2Use = app.DAT.StimBlocks(idxss(1)).([chan,'_APTemplate']).AVG_RAW;
            temp2Use(temp2Use==Inf) = NaN;
            temp2Use(temp2Use==-Inf) = NaN;
            temp2Use = mean(temp2Use,'omitnan'); 
            apfilt = smooth(temp2Use,15,'sgolay');
            plot(app.FigPlot,temp2Use);
            hold(app.FigPlot,'on');
            app.APInfoPlots = plot(app.FigPlot,apfilt);
            app.APInfoPlots(2) = plot(app.FigPlot,0,0,'r');% R- START OF AP
            app.APInfoPlots(3) = plot(app.FigPlot,0,0,'g');% G- MAX PT OF AP
            app.APInfoPlots(4) = plot(app.FigPlot,0,0,'b');% B- SECOND MAX PT
            app.APInfoPlots(5) = plot(app.FigPlot,0,0,'k');% K- MAX HYPERPOLARIZATION
            app.APInfoPlots(6) = plot(app.FigPlot,0,0,'m');% M- END OF AP
            app.FigPlot.XLim(1) = 0;
            app.FigPlot.XLim(2) = 400;
            app.figure1.WindowButtonDownFcn = @(src,event)app.APInfoMouseDown;
            app.figure1.WindowButtonMotionFcn = @(src,event)app.APInfoMoseMove;
            app.figure1.WindowButtonUpFcn = @(src,event)app.APInfoMouseUp;
            uiwait
            app.figure1.WindowButtonDownFcn = [];
            app.figure1.WindowButtonMotionFcn = [];
            app.figure1.WindowButtonUpFcn = [];

            if ~isprop(app.DAT,'APRefProperties')
                app.DAT.addprop('APRefProperties')
            end
            chUsed = app.DAT.GetChan(chan);
            app.DAT.APRefProperties.([chan]).apTemp = apfilt;
            app.DAT.APRefProperties.([chan]).apSamps = app.APInfoPlots(6).XData(1)-app.APInfoPlots(2).XData(1);
            app.DAT.APRefProperties.([chan]).apTime = (app.APInfoPlots(6).XData(1)-app.APInfoPlots(2).XData(1))/chUsed.SampleRate;
            app.DAT.APRefProperties.([chan]).startPt = app.APInfoPlots(2).XData(1);
            app.DAT.APRefProperties.([chan]).startPtVal = apfilt(app.APInfoPlots(2).XData(1));
            app.DAT.APRefProperties.([chan]).endPt = app.APInfoPlots(6).XData(1);
            app.DAT.APRefProperties.([chan]).endPtVal = apfilt(app.APInfoPlots(6).XData(1));
            app.DAT.APRefProperties.([chan]).peakDePol1 = app.APInfoPlots(3).XData(1);
            app.DAT.APRefProperties.([chan]).peakDePol1Val = apfilt(app.APInfoPlots(3).XData(1));
            app.DAT.APRefProperties.([chan]).peakDePol2 = app.APInfoPlots(4).XData(1);
            if app.APInfoPlots(4).XData(1)>0
                app.DAT.APRefProperties.([chan]).peakDePol2Val = apfilt(app.APInfoPlots(4).XData(1));
            else
                app.DAT.APRefProperties.([chan]).peakDePol2Val = 0;
            end
            app.DAT.APRefProperties.([chan]).maxHyperpol = app.APInfoPlots(5).XData(1);
            app.DAT.APRefProperties.([chan]).maxHyperpolVal = apfilt(app.APInfoPlots(5).XData(1));

            for i = 1:length(app.DAT.StimBlocks)
                app.DAT.StimBlocks(i).([chan,'_APTemplate']).apSamps = app.APInfoPlots(6).XData(1)-app.APInfoPlots(2).XData(1);
                app.DAT.StimBlocks(i).([chan,'_APTemplate']).apTime = (app.APInfoPlots(6).XData(1)-app.APInfoPlots(2).XData(1))/chUsed.SampleRate;
                app.DAT.StimBlocks(i).([chan,'_APTemplate']).startPt = app.APInfoPlots(2).XData(1);
                app.DAT.StimBlocks(i).([chan,'_APTemplate']).startPtVal = apfilt(app.APInfoPlots(2).XData(1));
                app.DAT.StimBlocks(i).([chan,'_APTemplate']).endPt = app.APInfoPlots(6).XData(1);
                app.DAT.StimBlocks(i).([chan,'_APTemplate']).endPtVal = apfilt(app.APInfoPlots(6).XData(1));
                app.DAT.StimBlocks(i).([chan,'_APTemplate']).peakDePol1 = app.APInfoPlots(3).XData(1);
                app.DAT.StimBlocks(i).([chan,'_APTemplate']).peakDePol1Val = apfilt(app.APInfoPlots(3).XData(1));
                app.DAT.StimBlocks(i).([chan,'_APTemplate']).peakDePol2 = app.APInfoPlots(4).XData(1);
                if app.APInfoPlots(4).XData(1)>0
                    app.DAT.StimBlocks(i).([chan,'_APTemplate']).peakDePol2Val = apfilt(app.APInfoPlots(4).XData(1));
                else
                    app.DAT.StimBlocks(i).([chan,'_APTemplate']).peakDePol2Val = 0;
                end

                app.DAT.StimBlocks(i).([chan,'_APTemplate']).maxHyperpol = app.APInfoPlots(5).XData(1);
                app.DAT.StimBlocks(i).([chan,'_APTemplate']).maxHyperpolVal = apfilt(app.APInfoPlots(5).XData(1));
            end

            cla(app.FigPlot)
            app.FigPlot.Visible = "off";
            app.TabGroup.Position = [449 17 842 549];
            app.StartofAPButton.Visible = "off";
            app.StartofAPButton.Value = 0;
            app.StartofAPButton.BackgroundColor = [0.96,0.96,0.96];
            app.MaxDepolarization1Button.Visible = "off";
            app.MaxDepolarization1Button.Value = 0;
            app.MaxDepolarization1Button.BackgroundColor = [0.96,0.96,0.96];
            app.MaxDepolarization2Button.Visible = "off";
            app.MaxDepolarization2Button.Value = 0;
            app.MaxDepolarization2Button.BackgroundColor = [0.96,0.96,0.96];
            app.MaxHyperpolarizationButton.Visible = "off";
            app.MaxHyperpolarizationButton.Value = 0;
            app.MaxHyperpolarizationButton.BackgroundColor = [0.96,0.96,0.96];
            app.MaxHyperpolarizationButton.FontColor = 'k';
            app.EndofAPButton.Visible = "off";
            app.EndofAPButton.Value = 0;
            app.EndofAPButton.BackgroundColor = [0.96,0.96,0.96];
            app.continueButton.Visible = "off";
            if ~isempty(app.TB)
                for i = 1:length(app.TB.Children)
                    if isprop(app.TB.Children(i),'Value')
                        app.TB.Children(i).Value = 'off';
                    end
                end
            end
        end

        % Button pushed function: SAVEDATAButton
        function SAVEDATAButtonPushed(app, event)
            D = app.DAT;
            app.ProgressBar.Title.String = 'Saving Processed Data, Estimate 3min';
            app.PBarObj.Position(3) = 1000;
            app.PBarObj.FaceColor = 'y';
            drawnow
            save([app.SaveDirectory.Value,'\',D.fname,'.SUR'],'D')
            app.PBarObj.FaceColor = 'g';
            app.ProgressBar.Title.String = 'Data Saved';
            pause(0.5)
            app.ProgressBar.Title.String = 'Waiting';
            app.PBarObj.Position(3) = 0;
        end

        % Value changed function: stopButton
        function stopButtonValueChanged(app, event)
            app.ChangeProgressBar([],[],[],1)
            uiresume(app.figure1)
        end

        % Button pushed function: FindAPs
        function FindAPsButtonPushed(app, event)
            cla(app.FigPlot)
            app.APLocPlot = [];
            app.FigPlot.Visible = "off";
            app.TabGroup.Position = [449 17 842 549];
            app.StartofAPButton.Visible = "off";
            app.StartofAPButton.Value = 0;
            app.StartofAPButton.BackgroundColor = [0.96,0.96,0.96];
            app.MaxDepolarization1Button.Visible = "off";
            app.MaxDepolarization1Button.Value = 0;
            app.MaxDepolarization1Button.BackgroundColor = [0.96,0.96,0.96];
            app.MaxDepolarization2Button.Visible = "off";
            app.MaxDepolarization2Button.Value = 0;
            app.MaxDepolarization2Button.BackgroundColor = [0.96,0.96,0.96];
            app.MaxHyperpolarizationButton.Visible = "off";
            app.MaxHyperpolarizationButton.Value = 0;
            app.MaxHyperpolarizationButton.BackgroundColor = [0.96,0.96,0.96];
            app.MaxHyperpolarizationButton.FontColor = 'k';
            app.EndofAPButton.Visible = "off";
            app.EndofAPButton.Value = 0;
            app.EndofAPButton.BackgroundColor = [0.96,0.96,0.96];
            app.continueButton.Visible = "off";
            app.FindAPThresholdEditField.Visible = 'on';
            app.FindAPThresholdEditField.Enable = "on";
            app.FindAPThresholdLabel.Visible = 'on';
            app.APThreshChange = 0;

            if ~isempty(app.TB)
                for i = 1:length(app.TB.Children)
                    if isprop(app.TB.Children(i),'Value')
                        app.TB.Children(i).Value = 'off';
                    end
                end
            end
            focus(app.figure1)
            datacursormode(app.figure1,'off')
            app.PlotRawDataSwitch.Visible = "on";
            app.PlotRawDataSwitchLabel.Visible = 'on';
            prevSwitch = app.PlotRawDataSwitch.Position;
            app.PlotRawDataSwitch.Position = [ 46 25 45 20];
            prevST = app.PlotRawDataSwitchLabel.Position;
            app.PlotRawDataSwitchLabel.Position = [ 25 5 82 22];
            app.TabGroup.SelectedTab = app.AnalysisPlotsTab;
            app.TabGroup.Position = [449 496 842 70];
            app.FigPlot.Visible = 'on';
            app.ArtAnalysisInstructions.Visible = 'off';
            app.TB = axtoolbar(app.FigPlot,{'pan','zoomin','zoomout','restoreview'});
%             if ~isempty(isgraphics(app.AnalysisPlotsTab.Children,'axes'))
%                 delete(app.AnalysisPlotsTab.Children(isgraphics(app.AnalysisPlotsTab.Children,'axes')))
%             end
            app.SplineTempButton.Visible = 'off';
            app.BinTempButton.Visible = 'off';
            app.ReSizeTempButton.Visible = 'off';

            app.ArtRemoveShorts.Visible = 'off';
            app.stopButton.Visible = 'off';
            app.continueButton.Visible = 'on';
            app.APInstructions.Visible = 'on';
            app.stopButton.Visible = 'on';
            app.stopButton.Value = 0;
            prevSPos = app.stopButton.Position;
            app.stopButton.Position = [650 16 54 22];
            NStim = 198;
            StimE =[];
            for i = 1:length(app.StimList.Value)
                StimE = [StimE str2num(app.StimList.Value{i})];
            end
            RefE = [];
            for i = 1:length(app.RefList.Value)
                RefE = [RefE str2num(app.RefList.Value{i})];
            end
            StimCur = [];
            for i = 1:length(app.CurrentList.Value)
                StimCur = [StimCur str2num(app.CurrentList.Value{i})];
            end
            chan = app.ChannelList.Value;

            idxss = app.DAT.GetBlockFromParams(NStim, StimE, RefE, StimCur, []);
            APC = ActionPotential_class(app.DAT);
            TC = Template_class(app.DAT);
            ZAP = ZapQuick_class(app.DAT);
            if app.DebugFlgAll > 1
                answer = nbuttondlg('Would you like to show the debug plots for Spike Marking',{'Yes','No','Yes To All','No To All'},'DefaultButton',4,'PromptTextHeight',50);
                switch answer
                    case 'Yes'
                        app.DebugFlgSingle = 1;
                        app.DebugFlgAll = 2;
                        debugFlg = 1;
                        showFindFlg = 1;
                    case 'No'
                        app.DebugFlgSingle = 0;
                        app.DebugFlgAll = 2;
                        debugFlg = 0;
                        showFindFlg = 0;
                    case 'Yes To All'
                        app.DebugFlgSingle = 0;
                        app.DebugFlgAll = 1;
                        debugFlg = 1;
                        showFindFlg = 1;
                    case 'No To All'
                        app.DebugFlgSingle = 0;
                        app.DebugFlgAll = 0;
                        debugFlg = 0;
                        showFindFlg = 0;
                end
            elseif app.DebugFlgAll == 1
                debugFlg = 1;
            else
                debugFlg = 0;
                showFindFlg = 0;
            end

            focus(app.figure1)

            APC.findAPSAfterSubtraction(idxss, chan, app, debugFlg, showFindFlg)

            app.PlotElectrodeCombinations.Enable = 'on';
            app.continueButton.Visible = 'off';
            app.APInstructions.Visible = 'off';
            app.stopButton.Visible = 'off';
            app.stopButton.Value = 0;
            app.stopButton.Position = prevSPos;

            app.PlotRawDataSwitch.Visible = "off";
            app.PlotRawDataSwitchLabel.Visible = 'off';
            app.PlotRawDataSwitch.Position = prevSwitch;
            app.PlotRawDataSwitchLabel.Position = prevST;
            if ~isempty(app.TB)
                for i = 1:length(app.TB.Children)
                    if isprop(app.TB.Children(i),'Value')
                        app.TB.Children(i).Value = 'off';
                    end
                end
            end
            app.FigPlot.Visible = 'off';
            app.TabGroup.Position = [449 17 842 549];
            app.ChangeProgressBar([],[],[],1)
            app.figure1.WindowButtonDownFcn = [];
            app.FindAPThresholdEditField.Visible = 'off';
            app.FindAPThresholdLabel.Visible = 'off';
            app.FindAPThresholdEditField.Enable = "off";
            datacursormode(app.figure1,'on')
            
        end

        % Button down function: TabGroup
        function TabGroupButtonDown(app, event)

        end

        % Button pushed function: AnalyzeMotion
        function AnalyzeMotionButtonPushed(app, event)
            MSC = MotionStim_class(app.DAT);
            app.MotionStimList.Items = {};
            datacursormode(app.figure1,'off')
            app.KeyPressed = 0;
            if ~isempty(isgraphics(app.AnalysisPlotsTab.Children,'axes'))
                delete(app.AnalysisPlotsTab.Children(isgraphics(app.AnalysisPlotsTab.Children,'axes')))
            end
            app.ArtAnalysisInstructions.Visible = 'off';
%                 if app.DebugFlgAll > 1
%                     answer = nbuttondlg('Would you like to show the debug plots for Aligning Sync Signals',{'Yes','No','Yes To All Debug Plots','No To All Debug Plots'},'DefaultButton',4,'PromptTextHeight',50);
%                     switch answer
%                         case 'Yes'
%                             app.DebugFlgSingle = 1;
%                             app.DebugFlgAll = 2;
%                             debugFlg = 1;
%                         case 'No'
%                             app.DebugFlgSingle = 0;
%                             app.DebugFlgAll = 2;
%                             debugFlg = 0;
%                         case 'Yes To All Debug Plots'
%                             app.DebugFlgSingle = 0;
%                             app.DebugFlgAll = 1;
%                             debugFlg = 1;
%                         case 'No To All Debug Plots'
%                             app.DebugFlgSingle = 0;
%                             app.DebugFlgAll = 0;
%                             debugFlg = 0;
%                     end
%                 elseif app.DebugFlgAll == 1
%                     debugFlg = 1;
%                 else
%                     debugFlg = 0;
%                 end
            app.DebugFlgSingle = 1;
            app.DebugFlgAll = 2;
            debugFlg = 1;
            MotionStim = MSC.FindMotionStim(debugFlg,app);
            if ~isprop(app.DAT,'MotionStim')
                app.DAT.addprop('MotionStim');
            end
            app.DAT.MotionStim = MotionStim;
            app.MotionStimList.Enable = 'on';
            app.PlotMotion.Enable = 'on';
            app.SAVEDATAButtonPushed([]);
        end

        % Value changed function: StartofAPButton
        function StartofAPButtonValueChanged(app, event)
            value = app.StartofAPButton.Value;
            if value
                app.StartofAPButton.BackgroundColor = 'r';
                L = app.FigPlot.XLim(2);
                app.APInfoPlots(2).XData = [L/2 L/2];
                app.APInfoPlots(2).YData = app.FigPlot.YLim;
                app.APInfoPlots(2).LineWidth = 1.5;
            else
                app.StartofAPButton.BackgroundColor = [0.96 0.96 0.96];
                app.APInfoPlots(2).XData = 0;
                app.APInfoPlots(2).YData = 0;
            end
        end

        % Value changed function: MaxDepolarization1Button
        function MaxDepolarization1ButtonValueChanged(app, event)
            value = app.MaxDepolarization1Button.Value;
            if value
                app.MaxDepolarization1Button.BackgroundColor = 'g';
                L = app.FigPlot.XLim(2);
                app.APInfoPlots(3).XData = [L/2 L/2];
                app.APInfoPlots(3).YData = app.FigPlot.YLim;
                app.APInfoPlots(3).LineWidth = 1.5;
            else
                app.MaxDepolarization1Button.BackgroundColor = [0.96 0.96 0.96];
                app.APInfoPlots(3).XData = 0;
                app.APInfoPlots(3).YData = 0;

            end
        end

        % Value changed function: MaxDepolarization2Button
        function MaxDepolarization2ButtonValueChanged(app, event)
            value = app.MaxDepolarization2Button.Value;
            if value
                app.MaxDepolarization2Button.BackgroundColor = 'b';
                L = app.FigPlot.XLim(2);
                app.APInfoPlots(4).XData = [L/2 L/2];
                app.APInfoPlots(4).YData = app.FigPlot.YLim;
                app.APInfoPlots(4).LineWidth = 1.5;
            else
                app.MaxDepolarization2Button.BackgroundColor = [0.96 0.96 0.96];
                app.APInfoPlots(4).XData = 0;
                app.APInfoPlots(4).YData = 0;
            end
        end

        % Value changed function: MaxHyperpolarizationButton
        function MaxHyperpolarizationButtonValueChanged(app, event)
            value = app.MaxHyperpolarizationButton.Value;
            if value
                app.MaxHyperpolarizationButton.BackgroundColor = 'k';
                app.MaxHyperpolarizationButton.FontColor = [1 1 1];
                L = app.FigPlot.XLim(2);
                app.APInfoPlots(5).XData = [L/2 L/2];
                app.APInfoPlots(5).YData = app.FigPlot.YLim;
                app.APInfoPlots(5).LineWidth = 1.5;
            else
                app.MaxHyperpolarizationButton.BackgroundColor = [0.96 0.96 0.96];
                app.MaxHyperpolarizationButton.FontColor = 'k';
                app.APInfoPlots(5).XData = 0;
                app.APInfoPlots(5).YData = 0;

            end
        end

        % Value changed function: EndofAPButton
        function EndofAPButtonValueChanged(app, event)
            value = app.EndofAPButton.Value;
            if value
                app.EndofAPButton.BackgroundColor = 'm';
                L = app.FigPlot.XLim(2);
                app.APInfoPlots(6).XData = [L/2 L/2];
                app.APInfoPlots(6).YData = app.FigPlot.YLim;
                app.APInfoPlots(6).LineWidth = 1.5;
            else
                app.EndofAPButton.BackgroundColor = [0.96 0.96 0.96];
                app.APInfoPlots(6).XData = 0;
                app.APInfoPlots(6).YData = 0;
            end
        end

        % Button pushed function: AlignSyncSigs
        function AlignSyncSigsButtonPushed(app, event)
            focus(app.figure1)
            app.ArtAnalysisInstructions.Visible = 'off';
            MSC = MotionStim_class(app.DAT);
            datacursormode(app.figure1,'off')
            app.KeyPressed = 0;
            if ~isempty(isgraphics(app.AnalysisPlotsTab.Children,'axes'))
                delete(app.AnalysisPlotsTab.Children(isgraphics(app.AnalysisPlotsTab.Children,'axes')))
            end
            app.continueButton.Visible = 'on';
            app.KeepCEDSYNCButton.Visible = 'on';
            app.KeepFSYNCoButton.Visible = 'on';
            app.MotionSyncShorts.Visible = 'on';
              MSC.AlignSyncSignals(1,app)
            app.continueButton.Visible = 'off';
            app.KeepCEDSYNCButton.Visible = 'off';
            app.KeepFSYNCoButton.Visible = 'off';
            app.MotionSyncShorts.Visible = 'off';
        end

        % Value changed function: PlotRawDataSwitch
        function PlotRawDataSwitchValueChanged(app, event)
            value = app.PlotRawDataSwitch.Value;
            switch value
                case 'On'
                    app.APLocPlot.Children(3).Visible = 'on';
                case 'Off'
                    app.APLocPlot.Children(3).Visible = 'off';
            end
            
        end

        % Value changed function: FindAPThresholdEditField
        function FindAPThresholdEditFieldValueChanged(app, event)
            value = app.FindAPThresholdEditField.Value;
            uiresume(app.figure1)
            app.APThreshChange = 1;
            focus(app.figure1)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'SURsuite';
            app.figure1.Tag = 'figure1';
            app.ProgressBar = uiaxes(app.figure1);
            title(app.ProgressBar, 'Waiting')
            app.ProgressBar.XLim = [0 1000];
            app.ProgressBar.XTick = [];
            app.ProgressBar.YTick = [];
            app.ProgressBar.Tag = 'ProgressBar';
            app.FigPlot = uiaxes(app.figure1);
            zlabel(app.FigPlot, 'Z')
            app.FigPlot.Visible = 'off';
            app.LoadFile = uibutton(app.figure1, 'push');
            app.LoadFile.ButtonPushedFcn = createCallbackFcn(app, @LoadFile_Callback, true);
            app.LoadFile.Tag = 'LoadFile';
            app.LoadFile.Text = 'Load Spike2 or SUR File';
            app.text2 = uilabel(app.figure1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'File Path:';
            app.FilePathText = uieditfield(app.figure1, 'text');
            app.FilePathText.Tag = 'FilePathText';
            app.ChannelList = uilistbox(app.figure1);
            app.ChannelList.Items = {'Listbox'};
            app.ChannelList.ValueChangedFcn = createCallbackFcn(app, @ChannelList_Callback, true);
            app.ChannelList.Tag = 'ChannelList';
            app.ChannelList.Value = 'Listbox';
            app.text4 = uilabel(app.figure1);
            app.text4.Tag = 'text4';
            app.text4.Text = 'Channels';
            app.InitialChannelTemps = uibutton(app.figure1, 'push');
            app.InitialChannelTemps.ButtonPushedFcn = createCallbackFcn(app, @InitialChannelTemps_Callback, true);
            app.InitialChannelTemps.Tag = 'InitialChannelTemps';
            app.InitialChannelTemps.Text = {'Get Initial'; 'Templates'};
            app.StimList = uilistbox(app.figure1);
            app.StimList.Items = {'Listbox'};
            app.StimList.Multiselect = 'on';
            app.StimList.ValueChangedFcn = createCallbackFcn(app, @StimListValueChanged, true);
            app.StimList.Tag = 'StimList';
            app.StimList.Enable = 'off';
            app.StimList.Value = {'Listbox'};
            app.text5 = uilabel(app.figure1);
            app.text5.Tag = 'text5';
            app.text5.Text = 'STIMs';
            app.RefList = uilistbox(app.figure1);
            app.RefList.Items = {'Listbox'};
            app.RefList.Multiselect = 'on';
            app.RefList.ValueChangedFcn = createCallbackFcn(app, @RefListValueChanged, true);
            app.RefList.Tag = 'RefList';
            app.RefList.Enable = 'off';
            app.RefList.Value = {'Listbox'};
            app.text6 = uilabel(app.figure1);
            app.text6.Tag = 'text6';
            app.text6.Text = 'REFs';
            app.CurrentList = uilistbox(app.figure1);
            app.CurrentList.Items = {'Listbox'};
            app.CurrentList.Multiselect = 'on';
            app.CurrentList.ValueChangedFcn = createCallbackFcn(app, @CurrentListValueChanged, true);
            app.CurrentList.Tag = 'CurrentList';
            app.CurrentList.Enable = 'off';
            app.CurrentList.Value = {'Listbox'};
            app.text7 = uilabel(app.figure1);
            app.text7.Tag = 'text7';
            app.text7.Text = 'Current';
            app.SubtractArtifact = uibutton(app.figure1, 'push');
            app.SubtractArtifact.ButtonPushedFcn = createCallbackFcn(app, @SubtractArtifactButtonPushed, true);
            app.SubtractArtifact.Tag = 'AnalyzeCombos';
            app.SubtractArtifact.Enable = 'off';
            app.SubtractArtifact.Text = {'Subtract'; 'Artifact'};
            app.MotionStimList = uilistbox(app.figure1);
            app.MotionStimList.Items = {'Listbox'};
            app.MotionStimList.Tag = 'MotionStimList';
            app.MotionStimList.Enable = 'off';
            app.MotionStimList.Value = 'Listbox';
            app.text8 = uilabel(app.figure1);
            app.text8.Tag = 'text8';
            app.text8.Text = 'Motion';
            app.PlotMotion = uibutton(app.figure1, 'push');
            app.PlotMotion.ButtonPushedFcn = createCallbackFcn(app, @PlotMotionButtonPushed, true);
            app.PlotMotion.Tag = 'PlotMotion';
            app.PlotMotion.Enable = 'off';
            app.PlotMotion.Text = 'Plot Motion';
            app.TabGroup = uitabgroup(app.figure1);
            app.TabGroup.ButtonDownFcn = createCallbackFcn(app, @TabGroupButtonDown, true);
            app.AnalysisPlotsTab = uitab(app.TabGroup);
            app.AnalysisPlotsTab.Title = 'Analysis Plots';
            app.KeepFSYNCoButton = uibutton(app.AnalysisPlotsTab, 'state');
            app.KeepFSYNCoButton.Visible = 'off';
            app.KeepFSYNCoButton.Text = 'Keep FSYNC (o)';
            app.KeepFSYNCoButton.Value = true;
            app.KeepCEDSYNCButton = uibutton(app.AnalysisPlotsTab, 'state');
            app.KeepCEDSYNCButton.Visible = 'off';
            app.KeepCEDSYNCButton.Text = 'Keep CEDSYNC (*)';
            app.KeepCEDSYNCButton.Value = true;
            app.continueButton = uibutton(app.AnalysisPlotsTab, 'push');
            app.continueButton.ButtonPushedFcn = createCallbackFcn(app, @continueButtonPushed, true);
            app.continueButton.Visible = 'off';
            app.continueButton.Text = 'continue';
            app.MotionSyncShorts = uilabel(app.AnalysisPlotsTab);
            app.MotionSyncShorts.Visible = 'off';
            app.MotionSyncShorts.Text = {'Shortcuts: c=CED, f=FSYNC'; 'b=both, backspace=Go Back'; 'Enter=continue'; ''; ''};
            app.SplineTempButton = uibutton(app.AnalysisPlotsTab, 'state');
            app.SplineTempButton.Visible = 'off';
            app.SplineTempButton.Text = {'Spline'; 'Temp'};
            app.BinTempButton = uibutton(app.AnalysisPlotsTab, 'state');
            app.BinTempButton.Visible = 'off';
            app.BinTempButton.Text = {'Bin'; 'Temp'};
            app.ReSizeTempButton = uibutton(app.AnalysisPlotsTab, 'state');
            app.ReSizeTempButton.Visible = 'off';
            app.ReSizeTempButton.Text = {'ReSize'; 'Temp'};
            app.ArtRemoveShorts = uilabel(app.AnalysisPlotsTab);
            app.ArtRemoveShorts.Visible = 'off';
            app.ArtRemoveShorts.Text = {'Shortcuts: s=Spline Template,'; 'b=Binned Template, R=Resized Template'; ''; ''};
            app.stopButton = uibutton(app.AnalysisPlotsTab, 'state');
            app.stopButton.ValueChangedFcn = createCallbackFcn(app, @stopButtonValueChanged, true);
            app.stopButton.Visible = 'off';
            app.stopButton.Text = 'stop';
            app.APInstructions = uilabel(app.AnalysisPlotsTab);
            app.APInstructions.Visible = 'off';
            app.APInstructions.Text = {'Click as close to a point to remove or a peak to add'; 'r = Hide/Show raw data, s = stop, enter = continue, z = left, x = right'};
            app.ArtAnalysisInstructions = uilabel(app.AnalysisPlotsTab);
            app.ArtAnalysisInstructions.Visible = 'off';
            app.ArtAnalysisInstructions.Text = {'Left click and hold on any plot to generate a larger popout of that figure,'; 'release to close the popout. Right click on the Spline Temp '; 'plot to edit the Spline Temp.'};
            app.PlotRawDataSwitchLabel = uilabel(app.AnalysisPlotsTab);
            app.PlotRawDataSwitchLabel.Visible = 'off';
            app.PlotRawDataSwitchLabel.Text = 'Plot Raw Data';
            app.PlotRawDataSwitch = uiswitch(app.AnalysisPlotsTab, 'slider');
            app.PlotRawDataSwitch.ValueChangedFcn = createCallbackFcn(app, @PlotRawDataSwitchValueChanged, true);
            app.PlotRawDataSwitch.Visible = 'off';
            app.PlotRawDataSwitch.Value = 'On';
            app.MotionStimPlotsTab = uitab(app.TabGroup);
            app.MotionStimPlotsTab.Title = 'Motion Stim Plots';
            app.MotionHigh = uiaxes(app.MotionStimPlotsTab);
            title(app.MotionHigh, 'Title')
            ylabel(app.MotionHigh, 'Y')
            zlabel(app.MotionHigh, 'Z')
            app.MotionMid = uiaxes(app.MotionStimPlotsTab);
            title(app.MotionMid, 'Title')
            ylabel(app.MotionMid, 'Y')
            zlabel(app.MotionMid, 'Z')
            app.MotionLow = uiaxes(app.MotionStimPlotsTab);
            title(app.MotionLow, 'Title')
            ylabel(app.MotionLow, 'Y')
            zlabel(app.MotionLow, 'Z')
            app.MotionNerveCon = uiaxes(app.MotionStimPlotsTab);
            title(app.MotionNerveCon, 'NerveCon')
            xlabel(app.MotionNerveCon, 'Time')
            ylabel(app.MotionNerveCon, 'ISR (HZ)')
            zlabel(app.MotionNerveCon, 'Z')
            app.XYZVel = uicheckbox(app.MotionStimPlotsTab);
            app.XYZVel.ValueChangedFcn = createCallbackFcn(app, @XYZVelValueChanged, true);
            app.XYZVel.Text = 'X/Y/Z-Vel';
            app.LRZVel = uicheckbox(app.MotionStimPlotsTab);
            app.LRZVel.ValueChangedFcn = createCallbackFcn(app, @LRZVelValueChanged, true);
            app.LRZVel.Text = 'LARP/RALP/Z-Vel';
            app.XYZAcc = uicheckbox(app.MotionStimPlotsTab);
            app.XYZAcc.ValueChangedFcn = createCallbackFcn(app, @XYZAccValueChanged, true);
            app.XYZAcc.Text = 'X/Y/Z-Acc';
            app.SaveMotionStimFig = uibutton(app.MotionStimPlotsTab, 'push');
            app.SaveMotionStimFig.ButtonPushedFcn = createCallbackFcn(app, @SaveMotionStimFigButtonPushed, true);
            app.SaveMotionStimFig.Text = 'Save Figure';
            app.SpikeProbabilityPlot = uitab(app.TabGroup);
            app.SpikeProbabilityPlot.Title = 'Spike Probability Plots';
            app.SaveSPPlot = uibutton(app.SpikeProbabilityPlot, 'push');
            app.SaveSPPlot.Text = 'Save Figure';
            app.text2_2 = uilabel(app.figure1);
            app.text2_2.Tag = 'text2';
            app.text2_2.Text = 'Save Directory:';
            app.SaveDirectory = uieditfield(app.figure1, 'text');
            app.SaveDirectory.Tag = 'FilePathText';
            app.PlotElectrodeCombinations = uibutton(app.figure1, 'push');
            app.PlotElectrodeCombinations.ButtonPushedFcn = createCallbackFcn(app, @PlotElectrodeCombinationsButtonPushed, true);
            app.PlotElectrodeCombinations.Enable = 'off';
            app.PlotElectrodeCombinations.Text = {'Plot Selected'; 'Electrode Combinations'};
            app.APShapeDetButton = uibutton(app.figure1, 'push');
            app.APShapeDetButton.ButtonPushedFcn = createCallbackFcn(app, @APShapeDetButtonPushed, true);
            app.APShapeDetButton.Text = {'AP Shape '; 'Details'};
            app.SAVEDATAButton = uibutton(app.figure1, 'push');
            app.SAVEDATAButton.ButtonPushedFcn = createCallbackFcn(app, @SAVEDATAButtonPushed, true);
            app.SAVEDATAButton.Text = {'SAVE'; 'DATA'};
            app.FindAPs = uibutton(app.figure1, 'push');
            app.FindAPs.ButtonPushedFcn = createCallbackFcn(app, @FindAPsButtonPushed, true);
            app.FindAPs.Tag = 'AnalyzeCombos';
            app.FindAPs.Enable = 'off';
            app.FindAPs.Text = {'Find'; 'APs'};
            app.AnalyzeMotion = uibutton(app.figure1, 'push');
            app.AnalyzeMotion.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeMotionButtonPushed, true);
            app.AnalyzeMotion.Tag = 'PlotMotion';
            app.AnalyzeMotion.Enable = 'off';
            app.AnalyzeMotion.Text = {'Analyze '; 'Motion'};
            app.StartofAPButton = uibutton(app.figure1, 'state');
            app.StartofAPButton.ValueChangedFcn = createCallbackFcn(app, @StartofAPButtonValueChanged, true);
            app.StartofAPButton.Visible = 'off';
            app.StartofAPButton.Text = 'Start of AP';
            app.MaxDepolarization1Button = uibutton(app.figure1, 'state');
            app.MaxDepolarization1Button.ValueChangedFcn = createCallbackFcn(app, @MaxDepolarization1ButtonValueChanged, true);
            app.MaxDepolarization1Button.Visible = 'off';
            app.MaxDepolarization1Button.Text = 'Max Depolarization 1';
            app.MaxDepolarization2Button = uibutton(app.figure1, 'state');
            app.MaxDepolarization2Button.ValueChangedFcn = createCallbackFcn(app, @MaxDepolarization2ButtonValueChanged, true);
            app.MaxDepolarization2Button.Visible = 'off';
            app.MaxDepolarization2Button.Text = 'Max Depolarization 2';
            app.MaxHyperpolarizationButton = uibutton(app.figure1, 'state');
            app.MaxHyperpolarizationButton.ValueChangedFcn = createCallbackFcn(app, @MaxHyperpolarizationButtonValueChanged, true);
            app.MaxHyperpolarizationButton.Visible = 'off';
            app.MaxHyperpolarizationButton.Text = 'Max Hyperpolarization';
            app.EndofAPButton = uibutton(app.figure1, 'state');
            app.EndofAPButton.ValueChangedFcn = createCallbackFcn(app, @EndofAPButtonValueChanged, true);
            app.EndofAPButton.Visible = 'off';
            app.EndofAPButton.Text = 'End of AP';
            app.AlignSyncSigs = uibutton(app.figure1, 'push');
            app.AlignSyncSigs.ButtonPushedFcn = createCallbackFcn(app, @AlignSyncSigsButtonPushed, true);
            app.AlignSyncSigs.Tag = 'PlotMotion';
            app.AlignSyncSigs.Enable = 'off';
            app.AlignSyncSigs.Text = {'Align Sync'; 'Signals'};
            app.FindAPThresholdLabel = uilabel(app.figure1);
            app.FindAPThresholdLabel.Visible = 'off';
            app.FindAPThresholdLabel.Text = {'FindAP '; 'Threshold'};
            app.FindAPThresholdEditField = uieditfield(app.figure1, 'numeric');
            app.FindAPThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @FindAPThresholdEditFieldValueChanged, true);
            app.FindAPThresholdEditField.Visible = 'off';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SURsuite_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)SURsuite_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end